classdef cellROI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                    matlab.ui.Figure
        ProjectiontypeButtonGroup2    matlab.ui.container.ButtonGroup
        DisplayROIsCheckBox           matlab.ui.control.CheckBox
        VarianceButton2               matlab.ui.control.RadioButton
        MaxButton2                    matlab.ui.control.RadioButton
        MeanButton2                   matlab.ui.control.RadioButton
        ProjectiontypeButtonGroup1    matlab.ui.container.ButtonGroup
        ReferenceButton               matlab.ui.control.RadioButton
        VarianceButton                matlab.ui.control.RadioButton
        MaxButton                     matlab.ui.control.RadioButton
        MeanButton                    matlab.ui.control.RadioButton
        pushbutton_recalcCorr         matlab.ui.control.Button
        uipanel10                     matlab.ui.container.Panel
        text36                        matlab.ui.control.Label
        text35                        matlab.ui.control.Label
        text33                        matlab.ui.control.Label
        edit_calcNeuropil_Ro          matlab.ui.control.EditField
        edit_calcNeuropil_Ri          matlab.ui.control.EditField
        pushbutton_calcNeuropil       matlab.ui.control.Button
        WhiteLevelPanel1              matlab.ui.container.Panel
        WhiteLevelSpinner1            matlab.ui.control.Spinner
        WhiteLevelPanel2              matlab.ui.container.Panel
        WhiteLevelSpinner2            matlab.ui.control.Spinner
        uipanel_loadROIs              matlab.ui.container.Panel
        checkbox_ImportROIs           matlab.ui.control.CheckBox
        edit_nROIsToLoad              matlab.ui.control.EditField
        text25                        matlab.ui.control.Label
        pushbutton_loadSavedROIs      matlab.ui.control.Button
        checkbox_loadNeuropilData     matlab.ui.control.CheckBox
        uipanel_saveCellF             matlab.ui.container.Panel
        statebutton_reserveCellID     matlab.ui.control.StateButton
        statebutton_excludePixelMask  matlab.ui.control.StateButton
        pushbutton_delROI             matlab.ui.control.Button
        text_subjectID                matlab.ui.control.Label
        edit_subjectID                matlab.ui.control.EditField
        edit_cellID                   matlab.ui.control.EditField
        text_cellID                   matlab.ui.control.Label
        pushbutton_saveCellF          matlab.ui.control.Button
        text_boxWidthXCorr            matlab.ui.control.Label
        edit_boxWidthXCorr            matlab.ui.control.EditField
        text20                        matlab.ui.control.Label
        edit_XCorrLowerBound          matlab.ui.control.EditField
        StackSizeText                 matlab.ui.control.Label
        pushbutton_loadStack          matlab.ui.control.Button
        uipanel_XCorr                 matlab.ui.container.Panel
        statebutton_selectCorrPixel   matlab.ui.control.StateButton
        axes4                         matlab.ui.control.UIAxes
        uipanel9                      matlab.ui.container.Panel
        statebutton_selectCircle      matlab.ui.control.StateButton
        edit_circRadius               matlab.ui.control.EditField
        text_circRadius               matlab.ui.control.Label
        statebutton_selectPolygon     matlab.ui.control.StateButton
        axes1                         matlab.ui.control.UIAxes
        axes2                         matlab.ui.control.UIAxes
        axes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        %Metadata
        Projections = ...
            struct('mean', [], 'max', [], 'var', [], 'ref', []) % Store projection matrices here
        SaveDir char %Dir for saved ROIs and projections
        PathName char
        FileName char 
        subjectID char % 
        sessionID char

        %Stack and projections
        stack double % Description
        img = cell(1,2) % Current images in Axes1 and Axes2
        cmap = colormap(repmat(linspace(0,1,256)',1,3)); %Gray color map for imaging data

        %ROIs
        roi = {}; %Cell array containing vertices of all ROIs
        cellf = {}; %Cell array containing mean cellular fluorescence for each ROI
        curr_ROI = []; %Vertices of current ROI patch
        curr_cellf = []; %Cellular fluorescence from current ROI
        cellMask = []; %Logical mask for the currently selected ROI
        cellMasksAll = []; %3D array to store all cell masks
        save_names = {}; %Cell array of strings; filenames for save
        check_overwrite = true; %Check before overwriting file on save
        reserveCellID = false(0); %Logical vector indicating excluded regions (excluded from neuropil and flagged in ROI file)
        excludePixelMask = false(0); %Logical vector indicating excluded regions (excluded from neuropil and flagged in ROI file)
        neuropilf = {}; %Cell array for each cell's neuropil signal as a function of time
        npPoly = polyshape; %Polygon vertices for the neuropil 'subtractmask' for each ROI polyshape

        %Current graphical data
        ginput = []; % X,Y coordinate of user-specified point
        ref_fig = []; % Holds small reference image of current ROI in each prior session

        %For longitudinal ROI tracking
        masterROIs_filename char % Description
        priorSessionIdx logical; % Description
        priorSessionDates string % Description
    end
    
    methods (Access = private)
        function closeReq_refFig(app)
            delete(app.ref_fig.figure);
            app.ref_fig = [];
            %delete_refFig(app); %Close ref fig
            refresh_Axis(app, 1:2);
        end
        
        function delete_refFig(app)
            %Clear any prior reference figure
            if ~isempty(app.ref_fig) && isfield(app.ref_fig,'figure')
                if isvalid(app.ref_fig.figure)
                    delete(app.ref_fig.figure);
                end
                app.ref_fig = [];
            end
        end
        
        function poly = getNpPoly(~, mask)
            %--- GENERATE ANNULUS REPRESENTING NEUROPIL MASK --------------------------
            %Generate polygon representation of neuropil mask (graphics object)
            
            B = bwboundaries(mask);
            warning('off','MATLAB:polyshape:repairedBySimplify');
            if size(B,1)==1 %Eg if annulus is interrupted and has no holes
                poly = polyshape(B{1}(:,2),B{1}(:,1)); %X-,Y-coordinates
            else
                nPoints = cellfun(@(C) size(C,1),B)'; %Number of points in each polygon
                start_idx = cumsum([0 nPoints+1])+1; %Starting idx for each series of points (series separated by NaN)
                X = NaN(sum(nPoints)+numel(nPoints)-1,1); %Initialize vector with space for NaN between each series of points
                Y = X;
                for i=1:numel(nPoints)
                    X(start_idx(i):start_idx(i)+nPoints(i)-1) = B{i}(:,2);
                    Y(start_idx(i):start_idx(i)+nPoints(i)-1) = B{i}(:,1);
                end
                poly = polyshape(X,Y);
            end
        end
        
        function rois = getMasterROIs(app, roi_dirs, master_filename)
            %Import ROIs from selected sessions (prior cellIDs should be reserved regardless)
                        
            nROIs = 0; %Initialize counter
            for i = 1:numel(roi_dirs)
                file_list = dir(fullfile(roi_dirs{i},'*cell*.mat'));
            
                for j = 1:numel(file_list)
                    %Load ROI data from MAT file
                    S = load(fullfile(roi_dirs{i}, file_list(j).name),'sessionID','bw','cellf');
                    if ~any(S.bw,'all') %Reserved ROI
                        continue
                    end                    
                    
                    fields = fieldnames(S);
                    for k = 1:numel(fields)
                        rois.(fields{k}){nROIs+1,1} = S.(fields{k});
                    end
                    [startIdx,endIdx] = regexp(file_list(j).name,'cell\d{3}');
                    rois.cell_ID{nROIs+1,1} = file_list(j).name(startIdx+4:endIdx);
            
                    [path,name,ext] = fileparts(roi_dirs{i});
                    temp = strjoin({name,ext},'');
                    rois.fname_stack{nROIs+1,1} = temp(5:end); %Remove prefix: 'ROI_'
                    temp = regexp(path,filesep,'split'); %Split the path according to the file separator ('\' etc)
                    rois.sessionID{nROIs+1,1} = temp{end}; %Take parent dir as session ID 
                    nROIs = nROIs+1;
                end
            end
            
            if isfile(master_filename)
                save(master_filename,'-STRUCT','rois','-append');
            else, save(master_filename,'-STRUCT','rois');
            end
        end
        
        function img = getProjection( app, axisID )
            %--- GET PROJECTION ASSOCIATED WITH EACH SET OF AXES -----------------------
            
            
            %Find active radio button and get white level for specified axis
            if axisID == 1
                selectedButton = app.ProjectiontypeButtonGroup1.SelectedObject;
                whiteLevel = app.WhiteLevelSpinner1.Value; %White levels
            else
                selectedButton = app.ProjectiontypeButtonGroup2.SelectedObject;
                whiteLevel = app.WhiteLevelSpinner2.Value; %White levels
            end
            
            %Get specified projection
            switch selectedButton.Text
                case 'Mean', img = app.Projections.mean;
                case 'Variance', img = app.Projections.var;
                case 'Max', img = app.Projections.max;
                case 'Reference'
                    % shift = [-app.slider_shiftY.Value app.slider_shiftX.Value];
                    % img = circshift(app.Projections.ref, shift);  %Apply translation if needed  
                    if ~isfield(app.Projections,"ref") || isempty(app.Projections.ref)
                        [filename, pathname] = ...
                            uigetfile('*.tif', 'Select a reference image (*.TIF).'); %Load single-frame tiff
                        app.Projections.ref = double(imread(fullfile(pathname,filename)));
                    end
                    img = app.Projections.ref;  %Apply translation if needed  
            end
            disp(['Axis ', num2str(axisID), ': ', selectedButton.Text]);

            %Adjust White Level
            img = whiteLevel .* (img./max(img(:))) * 256;
        end
        
        function save_name = getSaveName(app, idx)
            
            %Generate filename for save
            save_name = fullfile(strcat('cell',sprintf('%03d',idx),'.mat')); %If no prefix entered (default)
            
            %Check if already used and if so, get user input: overwrite or generate new filename
            if length(app.reserveCellID)>=idx && app.reserveCellID(idx) && app.check_overwrite
                %Confirm use of reserved cell ID
                A = questdlg(['Use reserved cell ID #', app.edit_cellID.Value '?'],...
                    'Reserved Cell ID', 'Use Reserved ID', 'New Filename', 'New Filename');
            elseif exist(fullfile(app.SaveDir, save_name),'File') && app.check_overwrite
                A = questdlg(['Overwrite ',save_name,'?'],'Overwrite ROI File','Overwrite','New Filename','Overwrite');
            end

            %Get new filename if necessary
            if exist('A', 'var') && strcmp(A,'New Filename')
                    idx = nxtCellID(app); %Increment cell ID for save
                    save_name = getSaveName(app, idx);
            end

        end
        
        function initUserData(app)
            %--- INITIALIZE USER DATA STRUCTURE (struct 'handles') --------------------
            
            app.subjectID = '';
            app.cmap = colormap(repmat(linspace(0,1,256)',1,3)); %Gray color map for imaging data
            app.roi = cell(999,1); %Cell array containing vertices of all ROIs
            app.cellf = cell(999,1); %Cell array containing mean cellular fluorescence for each ROI
            app.curr_ROI = []; %Vertices of current ROI patch
            app.curr_cellf = []; %Cellular fluorescence from current ROI
            app.cellMask = false(size(app.stack,1),size(app.stack,2)); %Logical mask for the currently selected ROI
            app.cellMasksAll = false(size(app.stack,1),size(app.stack,2)); %3D array to store all cell masks
            app.save_names = cell(999,1); %Cell array of strings; filenames for save
            app.check_overwrite = true; %Check before overwriting file on save
            app.excludePixelMask = false(999,1); %Logical vector indicating excluded regions (excluded from neuropil and flagged in ROI file)
            app.reserveCellID = false(999,1);
            app.neuropilf = cell(999,1); %Cell array for each cell's neuropil signal as a function of time
            app.npPoly = polyshape(); %Polygon vertices for the neuropil 'subtractmask' for each ROI polyshape
            
            %Default subjectID to Parent Directory name, containing subject ID with prefix 'm' followed by numbers, eg 'm199'
            temp = split(fileparts(app.PathName),filesep); 
            temp = regexp(temp{end},'[m]\d+','match'); 
            if isscalar(temp) %One and only one match found
                app.subjectID = temp{1}; 
                app.edit_subjectID.Value = temp{1}; %Else, field remains 'Enter subject ID...'
            end 

            %Get filenames for any existing ROIs
            f_list = dir(fullfile(app.SaveDir, '*cell*.mat'));
            for i = 1:numel(f_list) %Number of ROIs in data dir.
                idx = str2double(f_list(i).name(end-6:end-4)); %Get three-digit cell ID
                app.save_names{idx} = f_list(i).name; %Populate cell array of save names
            end
        end
        
        function loadImageStack( app )
            % ---Load Image Stack------------------------------------------------------
            %Load stack from file or input argument and generate associated projections

            %Check for saved data file roiData.mat
            app.stack = [];
            app.edit_subjectID.Value = 'Enter subject ID...';
            app.SaveDir = fullfile(app.PathName, strcat('ROI_', app.FileName)); %Path for ROI directory
            roiData_fname = fullfile(app.SaveDir, 'roiData.mat');
            if exist(roiData_fname,'file')
                S = load(roiData_fname,'max','mean','var');
                disp(['Loading saved data from ' roiData_fname ':']);
                
                %Store projections
                fields = ["max", "mean", "var"];
                for i = 1:numel(fields)
                    if isfield(S,fields(i))
                        app.Projections.(fields{i}) = S.(fields{i});
                    else
                        app.Projections.(fields{i}) = [];
                    end
                end

            else
                mkdir(app.SaveDir); %Setup subdir for saving files
                S = struct('FileName', app.FileName);
                save(roiData_fname,'-struct','S','-v7.3');
            end
            
            %Load stack using custom function loadtiffseq.m
                disp(['Loading ' app.PathName app.FileName '...']); tic
                img_stack = double(app.loadtiffseq(fullfile(app.PathName, app.FileName))); toc 
                app.stack = img_stack-min(img_stack(:)); %Recenter so all values are positive
            
            %Calculate mean, max, and variance projections to aid in ROI selection
            if isempty(app.Projections.var)
                tic;
                app.StackSizeText.Text = 'Getting projections...';
                
                disp('Getting mean projection...');
                P.mean = mean(app.stack,3);
                
                disp('Getting variance projection...');
                P.var = ...
                    squeeze(var(permute(app.stack, [3,1,2]), 0, 1)); %Time-dimension to D1 to speed calculation (tested 3-20x faster)
                
                disp('Getting maximum projection...');
                P.max = max(app.stack, [], 3); toc
                
                %Save projections in roiData.mat
                disp(['Saving all projections in ' roiData_fname '...']);
                save(roiData_fname,'-struct','P','-append'); disp('Done.');
                
                %Store in app properties
                app.Projections = P;
            end
            
            %Project data onto Axes 1 (left) & 2 (right)
            for imgIdx = 1:2 %Check radio buttons and get specified projection
                app.img{imgIdx} = getProjection(app, imgIdx); %img = getProjection(axisID,handles)
            end
            
            %Initialize user data structure and populate text/edit fields
            initUserData(app);
            app.edit_nROIsToLoad.Value = num2str(numel(app.save_names)); %Last cell ID
            app.edit_cellID.Value = '1'; %Cell ID for save
            app.MainFigure.Name = ['cellROI 3.0     ' app.FileName];
            [nY,nX,nZ] = size(app.stack);
            app.StackSizeText.Text = [num2str(nZ) ' frames of ' num2str(nX) 'x' num2str(nY) ' loaded.'];
        end
        
        function idx = nxtCellID(app)
            %--- INCREMENT CELL_ID FOR SAVE -------------------------------------------
            
            idx = find(cellfun(@isempty, app.save_names),1,'first'); %Find first unused savename
            if isempty(idx)
                idx = numel(app.save_names)+1;
            end
            
            %Modify app data
            app.edit_cellID.Value = num2str(idx);
            app.excludePixelMask(idx) = false;
            app.reserveCellID(idx) = false;
        end
        
        function plotROI_refFig(app, event)

            %Generate reference image for current ROI
            cla;
            
            %Get frame segment and adjust white (max) level
            idx = app.ref_fig.curr_idx;
            temp = app.ref_fig.pix{idx};
            thresh = prctile(temp, 99, "all"); %Set white level to 97th percentile
            temp(temp>thresh) = thresh;
            
            %Plot outline of ROI over frame segment
            imagesc(temp); hold on
            plot(app.ref_fig.roi{idx}(:,1),...
                app.ref_fig.roi{idx}(:,2),'r');
            text(1, 3, app.ref_fig.date{idx}); %Tag img with session date
            
            %Format axes
            ax = gca;
            ax.Position = [0,0,1,1]; %Eliminate white space
            ax.YTickLabel = []; ax.XTickLabel = [];
            axis square;
            
            %Increment session index
            i = app.ref_fig.curr_idx;
            if i < app.ref_fig.end_idx
                i = i+1;
            else
                i = 1;
            end
            app.ref_fig.curr_idx = i;
            app.ref_fig.figure.WindowButtonDownFcn = @(obj,event)app.plotROI_refFig;
        end
        
        function refFig_priorROIs(app, cell_ID, width)
            %--- CREATE REFERENCE IMAGES FROM PRIOR ROIS ------------------------------
            % Generate interactive figure containing reference images from prior sessions
                        
            %Clear any prior reference figurecell_ID
            delete_refFig(app);
            
            %Retrieve image segment from each prior session containing ROI
            load(app.masterROIs_filename,'frameSegment','bw'); %frameSegment is a struct generated by script screenROIs.m
            
            if ~exist('frameSegment') %Alert Message
                warndlg('No reference image for current ROI. Always update longitudinal data by running the script "screenROIs.m" before starting a new ROI selection session.','Warning');
                return
            end
            
            idx = find(strcmp({frameSegment.cell_ID}, cell_ID),1,'first'); %cell_ID = '001'
            frameSegment = frameSegment(idx); %Abridge struct to include only current cell
            [nY,~] = size(bw{1});
            
            %Warn if ROI not found in prior sessions
            if isempty(idx)
                warning('No prior sessions found containing this cell_ID. Check directory structure, then run script "screenROIs.m" to obtain reference images.');
                return
            end
            
            %Figure position
            Y = frameSegment.centroid{1}(2);
            if Y < 0.5*nY
                fig_pos = [100,100,width,width];
            else fig_pos = [100,800,width,width];
            end
            
            %Image ROI and surrounding pixels for each session
            app.ref_fig = frameSegment; %Store frames to advance on mouse click
            app.ref_fig.curr_idx = 1; %Initialize image index
            app.ref_fig.end_idx = numel(frameSegment.pix); %Idx of last session (chronological)
            
            fig = figure('Visible','on','NumberTitle','off','WindowStyle','normal'); %Create figure window
            fig.Position = fig_pos;
            fig.MenuBar = 'none';
            fig.ToolBar = 'none';
            fig.Name = 'Reference Image';
            app.ref_fig.figure = fig; %Store figure for later reference
            
            app.ref_fig.figure.WindowButtonDownFcn =...
                @(obj,event)app.plotROI_refFig; %On mouse click, create ROI ref image from next session
            app.ref_fig.figure.WindowKeyPressFcn =...
                @(obj,event)app.refocus_mainFig(event); %Focus on main GUI window if user presses a key
            app.ref_fig.figure.CloseRequestFcn =...
               @(obj,event)app.closeReq_refFig; %Focus on main GUI window if user presses a key
            
            plotROI_refFig(app);
            txt_y = size(frameSegment.pix{1},1) - 3; %y-position of message
            text(1,txt_y,{'Left click on image'; 'advances to next session.'},'Color','r');
        end
        
        function app = refocus_mainFig(app,event)
            MainFigure_WindowKeyPressFcn(app, event);
        end
        
        function refresh_Axis(app, axis_ID)
            
            %--- REFRESH AXES ---------------------------------------------------------
                      
            ax = {'axes1','axes2','axes3','axes4'}; %Fieldnames for all axes in handles structure
            currIdx = 0; %Current cell ID
            for i = axis_ID %Set of int axes_IDs passed to function
            
                %For each axis
                cla(app.(ax{i})); %Clear axes
                app.(ax{i}).TickLength = [0.001 0.01];
                app.(ax{i}).FontSize = 10;
                hold(app.(ax{i}),'on'); %Hold on
            
                %Axes1 and Axes2 (FOV axes)
                if ismember(ax{i},{'axes1','axes2'})
                    image(app.(ax{i}), app.img{i});  %Refresh image
                    colormap(app.(ax{i}),app.cmap);
                    axis(app.(ax{i}),'off','equal','ij');
            
                    if app.DisplayROIsCheckBox.Value% && ~all(cellfun(@isempty, app.roi))
                        %Refresh ROI patches
                        for j = find(~cellfun(@isempty, app.roi))' %Refresh saved ROIs
                            ROI = app.roi{j};
                            p = patch(app.(ax{i}),'XData',ROI(:,1),'YData',ROI(:,2),...
                                'FaceColor','y','EdgeColor','none','FaceAlpha',0.2); %Display ROI on specified axes
                            set(p,'ButtonDownFcn', @(obj,event)app.selectROI(obj),...
                                'PickableParts','all','HitTest','on');
                            if isequal(app.roi{j}, app.curr_ROI)
                                p.FaceColor = 'r';                 %Highlight current ROI in red;
                                currIdx = j; %Currently selected cell ID
                                text(app.axes1, 1, 3, 'Right click to deselect current ROI.','Color','r');
                            elseif app.excludePixelMask(j)
                                p.FaceColor = 'k'; p.FaceAlpha = 0.5; %Excluded rois in black
                            end

                            %Make all others transparent while assigning longitudinal ROI
                            if isfield(app.ref_fig,'figure')...
                                    && isvalid(app.ref_fig.figure)...
                                    && ~isequal(app.roi{j}, app.curr_ROI)
                                p.FaceAlpha = 0.05;
                            end
                        end
                        if ~currIdx && ~isempty(app.curr_ROI) %If newly drawn ROI
                            patch(app.(ax{i}),'XData',app.curr_ROI(:,1),'YData',app.curr_ROI(:,2),...
                                'FaceColor','r','EdgeColor','none','FaceAlpha',0.4); %Display ROI on specified axes
                        end
                        %Refresh neuropil masks if needed
                        for j = find(~cellfun(@isempty,{app.npPoly.Vertices})) %Refresh saved neuropil masks
                            plot(app.(ax{i}),app.npPoly(j),...
                                'FaceColor','c','EdgeColor','none','FaceAlpha',0.2);
                        end
                    end
            
                elseif strcmp(ax{i},'axes3') %Refresh axes3 (dF/F plot)
                    cellf = NaN(size(app.stack,3),1); 
                    if ~isempty(app.curr_cellf)
                        cellf = app.curr_cellf;
                        cellf = cellf - min(cellf); %Offset cellular fluorescence data by min(F) for approx dF/F0; F0 should be positive
                    end
            
                    plot(app.axes3,(1:length(cellf)),... %Plot cell dF/F
                        (cellf-median(cellf)) / median(cellf),'k','LineWidth',1);
                    axis(app.axes3,'tight');
                    xlim(app.axes3,[0 length(cellf)]);
                    if currIdx && ~isempty(app.neuropilf{currIdx}) %Plot neuropil signal as dF/F
                        npf = app.neuropilf{currIdx};
                        plot(app.axes3,(1:length(npf)),(npf-median(npf)) / median(cellf),'c'); %Same scale as cellF
                    end
            
                elseif strcmp(ax{i},'axes4') %Refresh axes4 (Pearson's R histogram)
                    %Nothing yet!
                end
            end
        end
        
        function app = resizeROI(app, keypressed)
            %---RESIZE ROI-------------------------------------------------------------
            
            %Modify ROI by adding/subtracting pixels to top, bottom, right, left
            if strcmp(keypressed,'uparrow')
                dir = 'post';
                val = true;
            elseif strcmp(keypressed,'downarrow')
                dir = 'pre';
                val = false;
            end
            
            if isempty(app.cellMask)
                [m,n,~] = size(app.stack);
                BW = poly2mask(app.curr_ROI(:,1),app.curr_ROI(:,2),m,n);
            else
                BW = app.cellMask;
                top = (padarray(logical(diff(BW,1,1)),[1,0],0,dir)); %Find edges from TOP and set logical idx to 1
                bottom = (flip(padarray(logical(diff(flip(BW,1),1,1)),[1,0],0,dir),1)); %BOTTOM
                left = (padarray(logical(diff(BW,1,2)),[0,1],0,dir)); %LEFT
                right = (flip(padarray(logical(diff(flip(BW,2),1,2)),[0,1],0,dir),2)); %RIGHT
                BW(top|bottom|left|right) = val;
            end
            app.cellMask = BW;
            [app.curr_ROI, app.curr_cellf] = roiData(app);
        end
        
        function [roi, cellf] = roiData( app )
            %--- GET ROI VERTICES AND ASSOCIATED CELLULAR FLUORESCENCE -----------------
            
            %Display current ROI as patch on left and right axes
            bounds = bwboundaries(app.cellMask); %Boundaries of logical mask for current ROI
            roi = [bounds{1}(:,2) bounds{1}(:,1)]; %Boundary coordinates in ij
            
            %Get cellF as mean per-pixel fluorescence and plot the dF/F on bottom panel
            cellf = NaN(size(app.stack,3),1);
            for i=1:size(app.stack,3)
                cellf(i) = sum(sum(app.stack(:,:,i).*double(app.cellMask))); %Pre-20018b syntax for back-compatibility
            end
            cellf = cellf/sum(sum(app.cellMask));
        end
        
        function saveRefImg(app)
            %--- SAVE FULL FOV REFERENCE IMAGE WITH ROIS ------------------------------
            %   -Can be used eg for longitudinal imaging
            
            %Plot all ROIs
            f = figure('Visible','off','Position',[100, 100, size(app.stack,1), size(app.stack,2)]);
            ax = copyobj(app.axes2,f);
            ax.YLim = [0 size(app.stack,1)];
            ax.XLim = [0 size(app.stack,2)];
            %Label with cellIDs
            for i = 1:numel(app.roi)
                if isempty(app.roi{i}) %empty roi indicated reserved cellID from current or other session
                    continue
                else
                saveNum = join(string(regexp(app.save_names{i},'\d','match')),'');
                pos = mean(app.roi{i});
                text(pos(1),pos(2),saveNum,"HorizontalAlignment","center","Color",'c');
                end
            end
            %Write image to file
            set(ax,'Units','pixels','Position',[0, 0, ax.YLim(2), ax.XLim(2)],'LooseInset',[0,0,0,0]);
            img = getframe(ax);
            %TIFF
            imwrite(img.cdata,fullfile(app.SaveDir,'ROI_Image.tif'),'tif');
            %PNG
            imwrite(img.cdata,fullfile(app.SaveDir,'ROI_Image.png'));
        end
        
        function selectROI( app, obj)
            
            %--- SELECT ROI -------------------------------------------------------------
            for i = 1:numel(app.roi)
                if isequal(obj.Vertices, app.roi{i}), idx = i; end %Find the index of clicked ROI
            end
            radius = sqrt(polyarea(obj.XData, obj.YData)/pi); %Approximate radius
            
            %Highlight current ROI
            app.curr_ROI = app.roi{idx}; %Make these vertices the current ROI
            app.curr_cellf = app.cellf{idx}; %Set corresponding cellF as current cellF
            app.edit_cellID.Value = num2str(idx); %Make ROI idx current
            app.statebutton_excludePixelMask.Value = app.excludePixelMask(idx); %Update state of toggle button
            
            %Update logical mask for current ROI
            app.cellMask = app.cellMasksAll(:,:,idx);
            
            %Display approximate radius in pixels
            R = ceil(radius*2)/2; %Radius with resolution of 0.5 pixels
            app.edit_circRadius.Value = num2str(R);
            
            %Display distribution of mean pixel intensity for current ROI vs. surround
            [Y,X] = find(app.cellMasksAll(:,:,idx));
            centroid.X = mean(X);
            centroid.Y = mean(Y);
            R(1) = str2double(app.edit_calcNeuropil_Ri.Value); %Number of cell radii for inner diameter
            R(2) = str2double(app.edit_calcNeuropil_Ro.Value); %Number of cell radii for outer diameter
            for j = 1:2
                [X,Y] = meshgrid(1:size(app.stack,2),1:size(app.stack,1)); %Get grids within R(j) cell radii of centroid
                circle{j} = sqrt((X - centroid.X).^2 + (Y - centroid.Y).^2)...
                    <= R(j) * radius;   %Inner or outer radius of annulus in units of cell radius
            end
            %Get logical masks for current ROI, all other ROIs, and surround
            temp = true(size(app.cellMasksAll,3),1);
            temp(idx) = false; %Idx for all except current cell mask
            otherCellMask = logical(sum(app.cellMasksAll(:,:,temp),3)); %Mask for all saved ROIs except current
            cell_mask = app.cellMasksAll(:,:,idx) & ~otherCellMask;
            surroundMask = circle{2} & ~(circle{1} | cell_mask | otherCellMask); %Mask for circular region surrounding ROI, excluding inner circle and somata mask
            
            %Plot histogram of max pixel intensity
            cla(app.axes4);
            hold(app.axes4,'on');
            F_cellMask = mean(app.Projections.max(cell_mask)); %Grand mean fluorescence within current ROI
            F_surround = app.Projections.max(surroundMask);   %Mean fluorescence for each pixel in surround
            [counts,edges] = histcounts(F_surround);
            bar(app.axes4,edges(1:end-1),counts,'histc');
            plot(app.axes4,[F_cellMask F_cellMask],[0 1.1*max(counts)],'r','LineWidth',1);
            text(app.axes4,mean(xlim),max(ylim),{'Hist of max intensity for surrounding pixels';'line: current ROI'});
            
            temp = [edges,F_cellMask];
            rel_F = (F_cellMask - mean(F_surround))/mean(F_surround);
            text(app.axes4,max(temp),max(counts),num2str(rel_F,3));
            xlim(app.axes4,[min(temp),1.2*max(temp)]);
            if sum(counts)>0
            ylim(app.axes4,[0,1.1*max(counts)]); %ylim set to 1.1 * maximum count
            end
            %-----------
            
            %If checkbox 'Import prior ROIs' checked, popup figure with prior ROIs
            if app.checkbox_ImportROIs.Value
                cell_ID = sprintf('%03d',str2double(app.edit_cellID.Value)); %format of cell ID from .mat files, eg '004'
                width = 200;
                app.refFig_priorROIs(cell_ID, width); %Bring up figure with images of ROI from prior sessions
            end

            refresh_Axis(app, 1:3);
        end
        
        function transferROIs(app, data_dir)
            %--- TRANSFER ROIS FROM ADDITIONAL SESSION(S) -----------------------------
            
            %Note: Parents of ROI dir(s) to load must be two levels up from current ROI dir.
            
            % MAT file to save all ROIs from a given subject
            if isempty(app.subjectID)
                app.subjectID = char(inputdlg('Enter search filter (eg experimental subject ID).','Find Prior ROIs')); %User enters subject name, or other string to use as filter for dirs to search
            end            
            app.masterROIs_filename = fullfile(data_dir,['master_rois_' app.subjectID '.mat']); %Master ROI file

            %Get session directories
            temp = dir(fullfile(data_dir,['*' app.subjectID '*']));
            for i = 1:numel(temp)
                dirs.sessions{i,1} = fullfile(data_dir,temp(i).name);
            end     
            
            %Get ROI directories (within session dirs)
            rmIdx = false(size(dirs.sessions));
            dirs.roi = cell(size(dirs.sessions));
            for i = 1:numel(dirs.sessions)
                temp = dir(fullfile(dirs.sessions{i},'ROI*.tif'));
                temp = temp(temp.isdir); %Get only the directories
                if ~isempty(temp) %Exclude dirs not tagged with ROI* in name
                    dirs.roi{i,:} = fullfile(dirs.sessions{i}, temp.name);
                else
                    rmIdx(i) = true;
                end
            end
            dirs.sessions(rmIdx) = [];
            dirs.roi(rmIdx) = [];
            
            %Call getMasterROIs() to aggregate all ROIs from previous sessions
            rois = app.getMasterROIs(dirs.roi, app.masterROIs_filename);
            
            %Restrict by session
            status = app.selectPriorSessions(dirs.roi); %Populates app.priorSessionIdx           
            if status==false || all(app.priorSessionIdx==false) 
                return %Abort if Cancel or no selection
            end
            app.priorSessionDates = app.priorSessionDates(app.priorSessionIdx); %Get selected session dates
            sessionIDParts = split(rois.sessionID,'-'); %Split session ID, formatted as 'date-subject-mazeID' 
            idx = ismember(sessionIDParts(:,1), app.priorSessionDates); %Index rois by prefix (session date)
            
            %Get all cell IDs for specified subject
            cell_IDs = unique(rois.cell_ID); 

            %Restrict Load to selected ROIs            
            for f = string(fieldnames(rois))'
                rois.(f) = rois.(f)(idx);
            end
            
            %Load first chronological instance of each ROI
            
            disp(['Loading ' num2str(numel(cell_IDs)) ' ROIs saved from previous sessions...']);
            for i = 1:numel(cell_IDs)
                selectedIdx = find(ismember(rois.cell_ID, cell_IDs{i})); %All indices in masterfile from selected sessions for current cell
                save_name = strcat('cell', cell_IDs{i}, '.mat');
            
                %ROIs from first session in which identified (unless excluded --> rois.bw should then init as [])
                cellIdx = selectedIdx(find(~cellfun(@isempty, rois.cellf(selectedIdx)), 1, 'first'));
                isExcludeMask = sum(cellfun(@(C) any(C(:)>0), rois.bw(selectedIdx)))>0 ...
                    & cellfun(@isempty, rois.cellf(selectedIdx)); %Don't import exclusion masks
                isReservedCell = ~any(ismember(rois.cell_ID, cell_IDs{i})); %Reserved cells have array of false for bw

                %If no MAT file for this cellID, create one
                if isExcludeMask %Don't import exclusion masks
                    continue
                elseif  isReservedCell
                    disp(['Reserving ', cell_IDs{i}])
                    bw = false(size(app.stack,[1,2]));
                else
                    bw = rois.bw{cellIdx};
                end

                if ~exist(fullfile(app.SaveDir, save_name),'file')
                    S = struct('sessionID', app.sessionID, 'cellf', [], 'bw', bw); %Store cell mask and dummy var for cellf in a structure
                    save(fullfile(app.SaveDir, save_name),'-struct','S'); %Save as eg 'cell004'
                end
            end
        end
        
        %Helper function: Draw Circle
        function drawCircle( app )
            %Determine the approx. circular logical mask
            X = app.ginput(1);
            Y = app.ginput(2);
            r = str2double(app.edit_circRadius.Value);
            [~,nX,~] = size(app.stack);
            [rr,cc] = meshgrid(1:nX); %Assume nX==nY
            app.cellMask = sqrt((rr-X).^2+(cc-Y).^2)<=r; %Get grids within circle radius
            %Store cellF and patch vertices in memory
            [app.curr_ROI, app.curr_cellf] = roiData(app);
            %Refresh Axes
            refresh_Axis(app, 1:3);
        end
            
        %Get ROIs from previous sessions
        function getPriorROIs(app, subjID, dataDir, frameWidth)
            
                %% Locate ROI data and Time-Projections from all Sessions from Specified Subject

                % Search for ROI directories
                temp = dir(fullfile(dataDir,['*' subjID '*']));            
                for i = 1:numel(temp)
                    dirs.sessions{i} = fullfile(dataDir,temp(i).name);
                end

                % MAT file to save all ROIs from a given subject
                master_file = fullfile(dataDir,['master_rois_' subjID '.mat']); %Master ROI file

                for i=1:numel(dirs.sessions)
                    temp = dir(fullfile(dirs.sessions{i},'ROI*.tif'));
                    temp = temp([temp.isdir]); %Get only the directories
                    if ~isempty(temp)
                        dirs.roi{i} = fullfile(dirs.sessions{i},temp.name);
                    end
                end
                dirs.roi = dirs.roi(~cellfun(@isempty,dirs.roi));

                %Get variance projection from each imaging session
                for i = 1:numel(dirs.roi)
                    S = load(fullfile(dirs.roi{i},'roiData.mat'),'var','FileName');
                    varProj{i}= S.var;
                    stackFileName{i} = S.FileName;
                end
                clearvars temp;

                %% Get frame segment and boundaries surrounding each ROI from each session

                %Get ROI data from all sessions
                S = app.getMasterROIs(dirs.roi, master_file); %Saves as 'master_rois_('subject_ID').mat'
                cellNum = str2double(unique(S.cell_ID));
                [nX,nY,~] = size(varProj{1});
                
                %Initialize
                frameSegment(cellNum(end)) = struct('cell_ID',[],'roi',[],'pix',[],'centroid',[],'date',[]);
                
                %Generate frame segment surrounding each ROI in each session
                for i = cellNum(:)'
                    idx = find(str2double(S.cell_ID) == i);
                    init = {cell(numel(idx),1)};
                    frameSegment(i) = struct('cell_ID',S.cell_ID{idx(1)},...
                        'roi',init,'pix',init,'centroid',init,'date',init); %Initialize           
                    for j = 1:numel(idx)
                        %Label with imaging date
                        [startIdx,endIdx] = regexp(S.fname_stack{idx(j)},'\d{6}');
                        frameSegment(i).date{j} = S.fname_stack{idx(j)}(startIdx:endIdx);

                        %Get X and Y boundaries of pixel region
                        [Y,X] = find(S.bw{idx(j)});
                        x = round(mean(X) + [-(frameWidth-1)/2, (frameWidth-1)/2]);
                        y = round(mean(Y) + [-(frameWidth-1)/2, (frameWidth-1)/2]);
                        %Set values outside frame to nearest pixel
                        x = max([x;1,1]); x = min([x;nX,nX]);
                        y = max([y;1,1]); y = min([y;nY,nY]);
                        %Get frame segment and ROI boundaries
                        proj = varProj{strcmp(stackFileName,S.fname_stack{idx(j)})};
                        frameSegment(i).pix{j} = proj(y(1):y(2),x(1):x(2));
                        frameSegment(i).centroid{j} = [mean(X) mean(Y)]; %Centroid of ROI in xy
                        cell_mask = S.bw{idx(j)}(y(1):y(2),x(1):x(2)); %Mask within segment
                        %Pad segments overlapping limit of FOV with zeros
                        if any(size(frameSegment(i).pix{j}) < frameWidth)
                            pix = frameSegment(i).pix{j};
                            [m,n] = size(pix);
                            padX = [0, frameWidth - n]; %Syntax for padarray(): (d1, d2)
                            padY = [frameWidth - m, 0];
                            if x(2)==nX %Right edge
                                pix = padarray(pix,padX,0,'post');
                                cell_mask = padarray(cell_mask,padX,0,'post');
                            elseif x(1)==1 %Left edge
                                pix = padarray(pix,padX,0,'pre');
                                cell_mask = padarray(cell_mask,padX,0,'pre');
                            end
                            if y(2)==nY %Bottom
                                pix = padarray(pix,padY,0,'post');
                                cell_mask = padarray(cell_mask,padY,0,'post');
                            elseif y(1)==1 %Top
                                pix = padarray(pix,padY,0,'pre');
                                cell_mask = padarray(cell_mask,padY,0,'pre');
                            end
                            frameSegment(i).pix{j} = pix;
                        end
                        bounds = bwboundaries(cell_mask); %Boundaries of logical mask for current ROI
                        frameSegment(i).roi{j} = [bounds{1}(:,2) bounds{1}(:,1)]; %Boundary coordinates in xy
                    end
                    
                    %Remove empty cells (ie, from reserved cell files)
                    idx = ~cellfun(@isempty,frameSegment(i).roi);
                    fields = string(fieldnames(frameSegment))';
                    for f = fields(fields~="cell_ID")
                        frameSegment(i).(f) = frameSegment(i).(f)(idx);
                    end
                end
                save(master_file,'frameSegment','-append');

                %% Create figure for each series of frame segments
                save_dir = fullfile(dataDir,['master_rois_' subjID]);
                mkdir(save_dir);
                for i = cellNum(:)'
                    idx = find(str2double(S.cell_ID) == i);
                    f = figure('Visible','on','NumberTitle','off');
                    %Subplot for each session
                    nRows = 10;
                    for j = 1:numel(idx)
                        p = subplot(2,numel(idx),j);
                        if isempty(frameSegment(i).pix{j})
                            continue; %Skip placeholder ROI files (reserved cell IDs)
                        end
                        imagesc(frameSegment(i).pix{j}); hold on
                        plot(frameSegment(i).roi{j}(:,1),frameSegment(i).roi{j}(:,2),'r');
                        p.YTickLabel = []; p.XTickLabel = [];
                        p.Title.String = frameSegment(i).date{j};
                        axis square;
                        q = subplot(nRows,numel(idx),(6*numel(idx))-(numel(idx)-j));
                        plot(S.cellf{idx(j)}); hold on;
                        q.YTickLabel = []; q.XTickLabel = [];
                    end
                    cellID = sprintf('%03d',i);
                    f.Name = ['cell_' cellID];
                    save_name = fullfile(save_dir,f.Name);
                    savefig(f,save_name);
                    saveas(f,save_name,'tif')
                    close(f);
                end
        end
        
        function stack = loadtiffseq(~, full_path )

            %Get frame-invariant tags
            t = Tiff(full_path);
            tagNames = ["ImageWidth","ImageLength","SampleFormat"]; %,"ImageDescription"
            for i = 1:numel(tagNames)
                tags.(tagNames(i)) =  t.getTag(tagNames(i));
            end

            %Initialize outputs
            while ~t.lastDirectory
                t.nextDirectory;
            end
            nFrames = t.currentDirectory;
            dataType = ["uint16", "int16"];
            stack = zeros(tags.ImageLength, tags.ImageWidth, nFrames,...
                dataType(tags.SampleFormat)); %SampleFormat indexes into 'dataType'

            %Read frames
            IFD = 1:nFrames; %Image File Dir (page) within TIFF
            for i = 1:size(stack,3)
                t.setDirectory(IFD(i));
                stack(:,:,i) = t.read();
            end
            close(t);
        end

        %Get projection for each prior FOV
        function projections = getMasterProjections(app, roi_dirs)
            roi_dirs = roi_dirs(~ismember(roi_dirs,app.SaveDir));
            for i = 1:numel(roi_dirs)
                S = load(fullfile(roi_dirs{i},'roiData.mat'),'var'); %Load variance projection
                projections(i).var = S.var;
                temp = split(fileparts(roi_dirs{i}),filesep);
                projections(i).sessionID = string(regexp(temp{end},'\d{6}','match')); %Dates are annotated as 'yymmdd'
            end
            app.priorSessionDates = [projections(:).sessionID];
        end       

        %Create figure for user to select sessions for prior ROI import
        function status = selectPriorSessions(app, roi_dirs)
            
            %Get variance projection for each session
            projections = getMasterProjections(app, roi_dirs); %Get projection for each prior FOV
            for i = 1:numel(projections)
                temp = projections(i).var;
                thresh = prctile(temp, 99, "all"); %Set white level to 97th percentile
                temp(temp>thresh) = thresh; 
                projections(i).var = temp;
            end
                        
            %Initialize idx for inclusion of prior sessions
            app.priorSessionIdx = true(1,numel(projections)); 
            
            %UI Figure
            fig = uifigure("Name", "Session Selection", "Position", [100,100,1600,800]);
            T = tiledlayout(fig, "flow", "TileSpacing","none","Padding","tight");
            for i = 1:numel(projections)
                ax(i) = nexttile(T); 
                imagesc(ax(i), projections(i).var); 
                axis(ax(i), "square", "off"); 
            end
            
            %One checkbox per tile, corresponding to each session date/ID
            cbx = gobjects(numel(ax),1); 
            for idx = 1:numel(ax)
                %label with session ID
                pos = getpixelposition(ax(idx), true); %Axes position in pixels, relative to figure position
                pos(3:4) = [100, 20];
                cbx(idx) = uicheckbox(fig, "Value", true, "Text", projections(idx).sessionID,...
                    "FontColor", 'r', "Position", pos, "ValueChangedFcn",...
                    @(obj,event)app.selectPriorSessions_checkBoxValueChangedFcn(obj,event,idx)); %checkbox for user input
            end
            
            %User instructions
            response = uiconfirm(fig,["Please select sessions to import"; "and then close Session Selection window."],...
                'Make Selection','Icon','info');
            
            %User response 
            if strcmp(response, 'Cancel') 
                close(fig);
                status = false;
                return
            else
                status = true;
            end
          
            %Wait for user to check/uncheck boxes and close uifigure
            uiwait(fig);
                       
        end
        
        %Store selections prior to closing session selection window
        function selectPriorSessions_checkBoxValueChangedFcn(app, obj, ~, idx)
            app.priorSessionIdx(idx) = obj.Value; %Value of checkbox object
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellselect_OpeningFcn(app, varargin)
            % --- Executes just before GUI is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.MainFigure, 'onscreen');
            
            % %Default command line output
            % handles.output = hObject;
            
            %Allow passage of stack, mean, var, max, as fields of struct as INPUT ARG #1
            if numel(varargin)>0
                if isinteger(varargin{1})
                    app.stack = varargin{1};
                elseif isstruct(varargin{1})
                    fields = fieldnames(varargin{1});
                    for i = 1:numel(fields)
                        app.(fields{i}) = varargin{1}.(fields{i});
                    end %fields = {'stack','mean','var','max','FileName'};
                end
            end
            
            if all(isfield(app,{'stack','FileName','PathName'}))
                loadImageStack(app);
                refresh_Axis(app, 1:4); %Refresh left & right axes
            end

        end

        % Value changed function: DisplayROIsCheckBox
        function ShowROIsCheckbox_Callback(app, event)
          
            %If using reference figs for longitudinal ROI selection
            if isfield(app,'ref_fig') && isfield(app.ref_fig,'figure')
                figure(app.ref_fig.figure); %refocus on ref fig
            end
            %In any case, refresh image axes to toggle display/hide ROIs
            refresh_Axis(app, 1:2);
        end

        % Value changed function: edit_XCorrLowerBound
        function edit_XCorrLowerBound_ValueChangedFcn(app, event)
            %Execute recalcCorr
            if app.statebutton_selectCorrPixel.Value
                app.pushbutton_recalcCorr_Callback;
            else %Engage Select Pixel state button
                app.statebutton_selectCorrPixel.Value = true;
            end
        end

        % Value changed function: edit_boxWidthXCorr
        function edit_boxWidthXCorr_Callback(app, event)
             %Execute recalcCorr
            if app.statebutton_selectCorrPixel.Value
                app.pushbutton_recalcCorr_Callback;
            else %Engage Select Pixel state button
                app.statebutton_selectCorrPixel.Value = true;
                statebutton_selectCorrPixel_ValueChanged(app);
            end
        end

        % Value changed function: edit_cellID
        function edit_cellID_Callback(app, event)
            %--- EDIT and CHECK BOXES (mostly unused callback functions) ----------------------
                      
            %Clear current ROI
            app.curr_ROI = []; 
            app.curr_cellf = []; %Set corresponding cellF as current cellF
            app.statebutton_excludePixelMask.Value = false; %Update state of toggle buttons
            app.statebutton_reserveCellID.Value = false;

            %Highlight specified ROI
            idx = str2double(app.edit_cellID.Value); %Make ROI idx current
            if size(app.roi,1)>=idx && ~isempty(app.roi{idx})
                app.curr_ROI = app.roi{idx}; %Make these vertices the current ROI
                app.curr_cellf = app.cellf{idx}; %Set corresponding cellF as current cellF
                app.statebutton_excludePixelMask.Value = app.excludePixelMask(idx); %Update state of toggle button
                app.statebutton_reserveCellID.Value = app.reserveCellID(idx); %Update state of toggle button
            elseif length(app.reserveCellID)>=idx && app.reserveCellID(idx)
                app.statebutton_reserveCellID.Value = true;
            else
               
            end
            %Refresh Axes
            refresh_Axis(app, 1:3);

        end

        % Value changed function: edit_circRadius
        function edit_circRadius_ValueChangedFcn(app, event)
            if app.statebutton_selectCircle.Value==true %Select circle engaged
                drawCircle(app); %Enlarge existing circle
            end
        end

        % Value changed function: edit_nROIsToLoad
        function edit_nROIsToLoad_Callback(app, event)
            pushbutton_loadSavedROIs_Callback(app, event)
        end

        % Size changed function: MainFigure
        function MainFigure_SizeChangedFcn(app, event)
            %--- SIZE CHANGED FUNCTION (executes when figure window is resized.)     
            try refresh_Axis(app, 1:4); catch; end
        end

        % Window button down function: MainFigure
        function MainFigure_WindowButtonDownFcn(app, event)
            %--- WINDOW BUTTONDOWN FUNCTION (executes on mouse click with focus on cellROI.fig) ---
            if strcmp(app.MainFigure.SelectionType,'alt')

                %Toggle State Buttons off
                app.statebutton_selectCircle.Value = false; 
                app.statebutton_selectPolygon.Value = false; 
                app.statebutton_selectCorrPixel.Value = false; 
                app.statebutton_reserveCellID.Value = false; 
                app.statebutton_excludePixelMask.Value = false; 

                %Clear any current selection
                app.curr_ROI = [];
                app.cellMask = [];
            
                %If using reference figs for longitudinal ROI selection
                if ~isempty(app.ref_fig)
                    delete_refFig(app); %Close ref fig
                end
                nxtCellID(app); %Increment cell ID
            
                try refresh_Axis(app, 1:4); catch; end %Try is used to avoid error in case user clicks before loading imaging data
            end
        end

        % Window key press function: MainFigure
        function MainFigure_WindowKeyPressFcn(app, event)
            %--- KEYPRESS FUNCTIONS (executes on keypress with focus on cellROI.fig) ---
            
            keyPressed = event.Key; %Determine the key that was pressed
            figure(app.MainFigure); %Focus on Main Figure

            if strcmpi(keyPressed,'s') %Save ROI and associated cellF
                pushbutton_savetraces_Callback(app);
            elseif strcmpi(keyPressed,'c') %Draw ROI with 'draw circle' callback function
                % app.statebutton_selectCircle.Value = false; %reset: toggle button off
                app.statebutton_selectCircle.Value = true;
                statebutton_selectCircleValueChanged(app);
            elseif strcmpi(keyPressed,'x') %Draw ROI with pixel correlation callback function
                app.statebutton_selectCorrPixel.Value = true;
                statebutton_selectCorrPixel_ValueChanged(app);
            elseif strcmpi(keyPressed,'z') %Draw ROI with 'draw polygon callback function
                app.statebutton_selectPolygon.Value = true;
                statebutton_selectPolygonValueChanged(app);
            elseif strcmpi(keyPressed,'e') %Draw ROI with previous pushbutton callback function
                app.statebutton_excludePixelMask.Value = ~(app.statebutton_excludePixelMask.Value); %Toggle button on/off
                statebutton_excludePixelMask_Callback(app);
            elseif strcmpi(keyPressed,'r') %Draw ROI with previous pushbutton callback function
                app.statebutton_reserveCellID.Value = true; %Toggle button on/off
                statebutton_reserveCellIDValueChanged(app);
            elseif strcmpi(keyPressed,'d') %Delete ROI data strcmpi(keyPressed,'delete') || strcmpi(keyPressed,'d')
                pushbutton_delROI_Callback(app); %DELETE ROI
            elseif (strcmpi(keyPressed,'uparrow') || strcmpi(keyPressed,'downarrow'))...
                    && ~isempty(strcmp(event.Modifier,'shift'))
                resizeROI(app, keyPressed); %Change ROI area by one pixel on each side
                refresh_Axis(app, 1:3);  %Refresh left & right axes
            end
        end

        % Window scroll wheel function: MainFigure
        function MainFigure_WindowScrollWheelFcn(app, event)
            % --- SCROLL-WHEEL FUNCTION (Executes on scroll wheel click while the figure is in focus)
           
            %If ROI selected and Pixel-correlation function active
            scrollClick = event.VerticalScrollCount;
            if ~isempty(app.curr_ROI)
                if app.statebutton_selectCorrPixel.Value
                    thresh = str2double(app.edit_XCorrLowerBound.Value);
                    thresh = max([min([thresh+0.05*scrollClick, 1]),0]);
                    app.edit_XCorrLowerBound.Value = num2str(thresh);
                    pushbutton_recalcCorr_Callback(app);
                   
                elseif app.statebutton_selectCircle.Value
                    %Increment radius
                    r = str2double(app.edit_circRadius.Value) + scrollClick;
                    app.edit_circRadius.Value = num2str(r);
                    %Determine the approx. circular logical mask
                    X = mean(app.curr_ROI(:,1)); %XY coordinates of centroid of circular ROI
                    Y = mean(app.curr_ROI(:,2));
                    [rr,cc] = meshgrid(1:size(app.stack,1));
                    app.cellMask = sqrt((rr-X).^2+(cc-Y).^2)<=r; %Get grids within circle radius
                    %Store cellF and patch vertices in memory
                    [app.curr_ROI, app.curr_cellf] = roiData(app);
                    refresh_Axis(app, (1:3)); %Display patch and corresponding DFF

                elseif app.statebutton_selectPolygon.Value
                    resizeROI(app, keypressed); %
            
                end
            end
        end

        % Button pushed function: pushbutton_calcNeuropil
        function pushbutton_calcNeuropil_Callback(app, event)
            % --- PUSHBUTTON: CALCULATE NEUROPIL SIGNALS... (Executes on button press in pushbutton_calcNeuropil.)
            % FUTURE EDITS: move toward high-level functions for area, centroid, etc...
            
            %Reload all saved ROIs
            app.edit_nROIsToLoad.Value = num2str(numel(app.save_names)); %Last cell ID
            app.checkbox_ImportROIs.Value = false; %Don't search for prior ROIs again if boxed checked
            pushbutton_loadSavedROIs_Callback(app);
            
            %Get centroid and radius of each ROI
            disp('Getting neuropil data...');
            idx = find(~cellfun(@isempty, app.save_names)); %Cell indices; skip unused save names
            centroid = struct('X',cell(numel(idx),1),'Y',cell(numel(idx),1)); %Initialize
            r_ROI = NaN(numel(idx),1);
            cellMask = false(size(app.stack,[1,2]));
            for i = idx'
                S = load(fullfile(app.SaveDir, app.save_names{i}),'bw'); %s.bw is logical mask for ROI
                [Y,X] = find(S.bw);
                centroid(i).X = mean(X);  %Centroid of ROI
                centroid(i).Y = mean(Y);
                r_ROI(i) = sqrt(sum(S.bw(:))/pi); %Circular ROI with r = sqrt(A/pi) used for estimating neuropil mask
                cellMask = cellMask | S.bw; %Incorporate ROI into logical mask for all cell bodies
            end
            clearvars X Y
            
            %Generate neuropil masks
            findex = 1:numel(app.save_names); %File index
            roiIdx = find(~cellfun(@isempty, app.save_names) &...
                ~app.excludePixelMask(findex) & ~app.reserveCellID(findex)); %Skip excluded ROIs and unused save names
            for i = roiIdx'
                disp(['Calculating neuropil signal for cell ' num2str(i) '...']);
                %Construct neuropil mask as annulus with user-defined inside and outside
                subtractmask = false(size(cellMask));
                circle = cell(2,1); %Cell array for the inner and outer circle
                R(1) = str2double(app.edit_calcNeuropil_Ri.Value); %Number of cell radii for inner diameter
                R(2) = str2double(app.edit_calcNeuropil_Ro.Value); %Number of cell radii for outer diameter
                for j = 1:2
                    %Get grids within R(j) cell radii of centroid
                    [X,Y] = meshgrid(1:length(subtractmask));
                    circle{j} = sqrt((X - centroid(i).X).^2 + (Y - centroid(i).Y).^2)...
                        <= R(j) * r_ROI(i);   %Inner or outer radius of annulus in units of cell radius
                end
                subtractmask = circle{2} & ~circle{1} & ~cellMask; %Exclude inner circle and somata mask
                subtractmaskRadii = R; %Record radii for later reference
            
                %Get neuropil fluorescence and save
                neuropilf = nan(size(app.stack,3),1); %Vector for neuropil signal
                idx = reshape(subtractmask,[],1); %Get linear logical indices for subtractmask
                for j = 1:size(app.stack,3)
                    img_vect = reshape(app.stack(:,:,j),[],1); %Convert the j-th frame to vector
                    neuropilf(j) = mean(img_vect(idx)); %Get mean pixel intensity
                end
                save(fullfile(app.SaveDir, app.save_names{i}),'neuropilf','subtractmask','subtractmaskRadii','-append');
            
                app.neuropilf{i} = neuropilf; %Store neuropil data
                app.npPoly(i) = getNpPoly(app, subtractmask); %Generate polygon representation (graphics object)
            end
            disp('Done!');

            refresh_Axis(app, 1:3);
        end

        % Button pushed function: pushbutton_delROI
        function pushbutton_delROI_Callback(app, event)
            
            % --- PUSHBUTTON: DELETE (Executes on button press in pushbutton_delROI or keypress 'delete')
                       
            idx = str2double(app.edit_cellID.Value);
            A = questdlg(strjoin({'Permanently delete', app.save_names{idx}, '?'}),...
                'Delete ROI','Ok','Cancel','Ok');
            if strcmp(A,'Ok')
                %Recycle file
                recycle('on');
                delete(fullfile(app.SaveDir, app.save_names{idx}));
                %Clear ROI data
                app.roi{idx} = [];
                app.cellf{idx} = [];
                app.cellMasksAll(:,:,idx) = ...
                    false(size(app.stack,1),size(app.stack,2)); %3D array of logical masks: nY x nX x nROIs
                app.curr_ROI = [];
                app.curr_cellf = [];
                app.excludePixelMask(idx) = false;
                app.reserveCellID(idx) = false;
                app.save_names{idx} = [];
                %Reset buttons
                % app.statebutton_excludePixelMask.Value = false;
            else
                return
            end

            %Delete reference figure (longitudinal imaging only)
            delete_refFig(app); 
            %Increment cell ID for save
            nxtCellID(app); 
            %Refresh Axes
            refresh_Axis(app, 1:4);

        end

        % Button pushed function: pushbutton_loadSavedROIs
        function pushbutton_loadSavedROIs_Callback(app, event)
            
            % --- PUSHBUTTON: LOAD (Executes on button press in pushbutton_loadSavedROIs.)
            
            %If IMPORT box checked, import all ROI dirs in current data dir (for longitudinal imaging)
            if app.checkbox_ImportROIs.Value
                parts = strsplit(app.PathName,filesep); %Split pathname
                data_dir = strjoin(parts(1:end-2),filesep); %Retrieve parent dir
                transferROIs(app, data_dir);
                initUserData(app);
                app.edit_nROIsToLoad.Value = num2str(numel(app.save_names));
            else
                initUserData(app); %Initialize struct userdata
            end
            
            for i = 1:numel(app.save_names)
            
                %Initialize reserved and excluded indices
                app.reserveCellID(i) = false;
                app.excludePixelMask(i) = false;

                %Stop loading ROIs at user-defined cell ID
                if i > str2double(app.edit_nROIsToLoad.Value) %Last three-digit cell ID to load
                    break
                elseif isempty(app.save_names{i})
                    continue
                end
            
                %Load saved ROI data
                warning('off','MATLAB:load:variableNotFound');
                try
                    S = load(fullfile(app.SaveDir, app.save_names{i}),...
                        'bw', 'cellf', 'subtractmask', 'neuropilf'); %s.bw is logical mask
                    S.bw = imresize(S.bw, size(app.stack,[1,2])); %Resize if necessary
                    
                    %Initialize ROI data
                    app.roi{i} = [];
                    app.cellf{i} = S.cellf;
                    app.cellMasksAll(:,:,i) = S.bw; %Append pixel mask to 3D array of logical masks: nY x nX x nROIs

                    %Handle any exclusion masks/reserved cells from previous sessions
                    app.excludePixelMask(i) = false;
                    app.reserveCellID(i) = false;
                    if ~any(S.bw,'all')
                        %Placeholders for reserved cells
                        cellID = join(string(regexp(app.save_names{i},'\d','match')),''); 
                        disp(strjoin(['Cell ID #', cellID, ' not loaded: reserved.']));
                        app.reserveCellID(i) = true;
                        continue
                    elseif isempty(S.cellf)  
                        %Generate exclude mask from saved data                         
                        app.excludePixelMask(i) = true; %Excluded ROIs have cellf=[]
                    end

                    %Load any neuropil masks
                    if isfield(S, 'subtractmask')
                        S.subtractmask = imresize(S.subtractmask,size(app.stack,[1,2])); %Resize if necessary
                        if app.checkbox_loadNeuropilData.Value
                            app.neuropilf{i} = S.neuropilf;         %Get saved neuropil data
                            app.npPoly(i) = getNpPoly(app, S.subtractmask); %Generate polygon representation (graphics object)
                        end
                    end

                    %Generate polygon representation of each cell mask
                    bounds = bwboundaries(S.bw);
                    app.roi{i} = [bounds{1}(:,2) bounds{1}(:,1)]; %ROI coordinates in xy
                                    
                catch err
                    disp(err); beep;
                    warning(['Problem with ' app.save_names{i}...
                        '. May require user to manually delete file and reload ROIs.']);
                end              
            
            end
            disp('Done!');

            nxtCellID(app); %Increment cell ID for save
            
            refresh_Axis(app, 1:4); %Refresh left & right axes
            saveRefImg(app) %Save a reference image of axes2 including ROIs
        end

        % Button pushed function: pushbutton_loadStack
        function pushbutton_loadimagestack_Callback(app, event)
            % ---PUSHBUTTON: LOAD STACK (executes on button press).
                          
            %Prompt user to select a .TIF stack containing calcium imaging data
            [app.FileName, app.PathName] = ...
                uigetfile('*.tif', 'Select a time-lapse image stack (*.TIF).'); %Get filename and path
            %If canceled, etc.
            if app.FileName==0
                return
            end

            %Session ID
            temp = strsplit(fileparts(app.PathName),filesep); %Split path at file separator
            app.sessionID = temp{end}; %

            %Clear data from any previous loaded stack
            app.Projections = struct('mean',[],'max',[],'var',[],'ref',[]);

            %Reset state buttons for ROI selection
            app.statebutton_selectCorrPixel.Value = false; %Pixel-correlation --> ROI
            app.statebutton_selectCircle.Value = false; %Circle ROI
            app.statebutton_selectPolygon.Value = false; %polygon ROI
            app.checkbox_ImportROIs.Value = false; %uncheck prior ROIs

            %Load stack and generate projections
            loadImageStack(app); %pathname and filename for stack as .TIF
            
            % guidata(hObject, handles); %Save user data
            refresh_Axis(app, 1:4);
        end

        % Button pushed function: pushbutton_recalcCorr
        function pushbutton_recalcCorr_Callback(app, event)
            %--- PUSHBUTTON: RECALC pixel correlation (executes on corresponding button press)
            %    note: also performs initial calculation after pushbutton SELECT PIXEL is pressed.
                        
            %Store active select-function in button state
            app.statebutton_selectCorrPixel.Value = true; %Pixel-correlation --> ROI
            app.statebutton_selectCircle.Value = false; %Circle ROI
            app.statebutton_selectPolygon.Value = false; %polygon ROI
            app.statebutton_excludePixelMask.Value = false; %Exclusion indicator

            %Determine square to correlate
            [nX,nY,~] = size(app.stack);
            X = app.ginput(1); %ginput from SELECT PIXEL pushbutton callback
            Y = app.ginput(2);
            
            if (X<0) || (X>nX) || (Y<0) || (Y>nY), return, end
            
            width = str2double(app.edit_boxWidthXCorr.Value);
            if isempty(width)
                %Get correlation with all pixels in frame
                x = [1,nX];
                y = [1,nY];
            else
                %Get X and Y boundaries of pixel region to correlate
                x = round(X + [-width/2, width/2]); 
                y = round(Y + [-width/2, width/2]);
                %Set values outside frame to nearest pixel
                x = max([x;1,1]); x = min([x;nX,nX]);
                y = max([y;1,1]); y = min([y;nY,nY]);
            end
            
            %Calculate matrix of correlation coefficients between seed and each pixel
            cc_mat = zeros(nY,nX);
            seedPixel = squeeze(app.stack(round(Y),round(X),:)); %Seed pixel
            for i = x(1):x(2)
                for j = y(1):y(2)
                    cc_mat(j,i) = corr2(...
                        seedPixel, squeeze(app.stack(j,i,:))); %Pixel (j,i)
                end
            end
            
            %Threshold by percentile specified in edit box
            temp = cc_mat(cc_mat>0);
            thresh_perc = min([max([0, str2double(app.edit_XCorrLowerBound.Value)*100]),100]);
            low_bound = prctile(temp, thresh_perc); %Get lower bound from edit box
            
            %Plot histogram of R-values
            cla(app.axes4);
            hold(app.axes4,'on');
            edges = -1:0.02:1;
            counts = histc(cc_mat(:),edges);
            bar(app.axes4,edges,counts,'histc');
            plot(app.axes4,[low_bound low_bound],[0 1.1*max(counts)],'r','LineWidth',1);
            xlim(app.axes4,[0,1]);
            temp = max(counts(edges>low_bound));
            
            if isempty(temp)
                return
            else
                %Display patch and corresponding DFF
                ylim(app.axes4,[0, 1.1*temp]); %ylim set to 1.1 * maximum count above threshold
                BW = (cc_mat>low_bound); %Get logical mask of pixels exceeding threshold
                BW = ~bwareaopen(~BW, 10, 8); %Remove small holes from pixel mask
                app.cellMask = bwareafilt(BW, 1, 4); %Keep only the largest region; 'conn'=4
                
                %Update vertices and cellF
                [app.curr_ROI, app.curr_cellf] = roiData(app);
                
                %Refresh Axes
                refresh_Axis(app, 1:3); 
            end
        end

        % Button pushed function: pushbutton_saveCellF
        function pushbutton_savetraces_Callback(app, event)
            %--- PUSHBUTTON: SAVE (executes on corresponding button press) ------------
            
            %Check for necessary variables
            if isempty(app.cellMask) %Eg if error in constructing mask or if saved cell selected
                return
            end
            
            %Check if save would overwrite; verify with user before continuing
            save_name = getSaveName(app, str2double(app.edit_cellID.Value));
            app.check_overwrite = true; %Set back to default: check before overwriting
            
            %Save data for current ROI
            S = struct('sessionID', app.sessionID, 'bw', app.cellMask, 'cellf', app.curr_cellf );
            save(fullfile(app.SaveDir, save_name),'-struct','S');
            
            %Store data in structure
            idx = str2double(app.edit_cellID.Value); %Edit box can be changed by function getSaveName(handles,idx)
            app.roi{idx} = app.curr_ROI; %Store vertices of current ROI patch
            app.cellMasksAll(:,:,idx) = app.cellMask; %3D array of logical masks: nY x nX x nROIs
            app.cellf{idx} = app.curr_cellf; %Store cellF from current ROI patch
            if ~isempty(app.curr_cellf)
                app.excludePixelMask(idx) = false; %Restore default, eg when transferring longitudinal ROIs
                app.reserveCellID(idx) = false;
            end
            
            app.save_names{idx} = save_name; %Store save_name
            nxtCellID(app); %Increment cell ID
            
            %Refresh axes
            app.curr_ROI = []; 
            app.curr_cellf = []; 
            app.cellMask = []; %Clear current ROI data
            refresh_Axis(app, 1:4);
            
            %Delete current reference figure
            delete_refFig(app); %Delete reference figure (longitudinal imaging only)

            %Reset State Buttons on Save
            app.statebutton_selectCircle.Value=false;
            app.statebutton_selectPolygon.Value=false;
            app.statebutton_selectCorrPixel.Value=false;

            app.statebutton_reserveCellID.Value = false;

        end

        % Value changed function: WhiteLevelSpinner1
        function WhiteLevelSpinner1ValueChanged(app, event)
            
            
            app.img{1} = getProjection(app, 1); %img = getProjection(axisID, handles)
            refresh_Axis(app, 1); %Refresh graphics and make current axis

        end

        % Value changed function: WhiteLevelSpinner2
        function WhiteLevelSpinner2ValueChanged(app, event)
            
            app.img{2} = getProjection(app, 2); %img = getProjection(axisID, handles)
            refresh_Axis(app, 2); %Refresh graphics and make current axis

        end

        % Value changed function: statebutton_excludePixelMask
        function statebutton_excludePixelMask_Callback(app, event)
            
            % --- TOGGLEBUTTON: EXCLUDE (Executes on button press in togglebutton_excludeROI.)
                       
            %Exclude/Include Pixels as ROI and save
            app.check_overwrite = false;
            if app.statebutton_excludePixelMask.Value
                idx = str2double(app.edit_cellID.Value); %Index prior to exclusion
                if isempty(app.cellf{idx}) %If excluded directly (not a prior selected ROI)
                    %Values between 901 and 999 reserved for Pixel Exclusion masks
                    tempMask = false(size(app.excludePixelMask)); %length(excludePixelMask)=999
                    tempMask(901:999) = true;
                    tempIdx = app.excludePixelMask & tempMask;
                    idx = find(tempIdx,1,"first")-1; %First assigned value 901<x<999
                    if isempty(idx)
                        idx = 999;
                    end
                end

                %Assign logical indices to reserve and exclusion masks
                app.edit_cellID.Value = num2str(idx);
                app.excludePixelMask(idx) = true; %For refreshing ROI mask
                app.reserveCellID(idx) = false;

                %Check if reserved
                if length(app.reserveCellID)>=idx && app.reserveCellID(idx) == true %For reserving cell idxs used in other sessions
                    app.check_overwrite = true; %Verify that user wants to use reserved idx for exclusion mask
                end
                
                app.curr_cellf = []; %Exclude ROI data
            else
                %For temporarily excluded pixels (ie, after importing prior ROIs)
                idx = str2double(app.edit_cellID.Value); %Get cell ID
                app.excludePixelMask(idx) = false;
                [~, app.curr_cellf] = roiData(app); %Get cellf and save to .MAT file
            end

            %Save and Increment cellID, etc.
            pushbutton_savetraces_Callback(app);  %Update .MAT file
            %Toggle button off
            app.statebutton_excludePixelMask.Value = false; 
            %Refresh Axes
            refresh_Axis(app, 1:3);
        end

        % Selection changed function: ProjectiontypeButtonGroup1
        function ProjectiontypeButtonGroup1SelectionChanged(app, event)

            app.img{1} = getProjection(app, 1);
            refresh_Axis(app,1);
        end

        % Selection changed function: ProjectiontypeButtonGroup2
        function ProjectiontypeButtonGroup2SelectionChanged(app, event)
            app.img{2} = getProjection(app, 2);
            refresh_Axis(app,2);

        end

        % Value changed function: statebutton_selectCircle
        function statebutton_selectCircleValueChanged(app, event)
            % --- STATEBUTTON: SELECT CIRCLE (executes on button press.)
            if app.statebutton_selectCircle.Value
                %Clear any current ROI
                app.curr_ROI = [];
                
                %Reset other associated buttons
                app.statebutton_selectCorrPixel.Value = false; %Pixel-correlation --> ROI
                app.statebutton_selectPolygon.Value = false; %Polygon ROI
                app.statebutton_excludePixelMask.Value = false; %Exclusion indicator
                
                %Refresh Axes
                refresh_Axis(app, 1:4);
             
                %Get selected pixel and test against frame boundaries
                [nY,nX,~] = size(app.stack);
                app.ginput = ginput(1); %#ok<ADPROPLC> %Store for use by helper functions, etc
                X = app.ginput(1);
                Y = app.ginput(2);
                if (X<0) || (X>nX) || (Y<0) || (Y>nY), return, end

                %Draw circle
                drawCircle(app);
            
            else %Button toggled 'off'
                app.curr_ROI = [];
                app.curr_cellf = [];
                app.cellMask = []; %Clear current ROI data **FUTURE: include in generalized reInit(varargin) function
            end
 
            %Refresh Axes
            refresh_Axis(app, 1:3);
            
        end

        % Value changed function: statebutton_selectCorrPixel
        function statebutton_selectCorrPixel_ValueChanged(app, event)
             %--- PUSHBUTTON: SELECT PIXEL for correlation (executes on corresponding button press)
            if app.statebutton_selectCorrPixel.Value
                %Clear any current ROI
                app.curr_ROI = [];
                
                %Reset other associated buttons 
                app.statebutton_selectCircle.Value = false; %Pixel-correlation --> ROI
                app.statebutton_selectPolygon.Value = false; %Polygon ROI
                app.statebutton_excludePixelMask.Value = false; %Exclusion indicator
                
                %Refresh Axes
                refresh_Axis(app, 1:4);

                %Get seed pixel specified by user
                app.ginput = ginput(1); %#ok<ADPROPLC> %[X,Y] = ginput(nPoints);

                %Calculate pixel-wise correlation
                pushbutton_recalcCorr_Callback(app); %Callback function for 'Recalc' pushbutton
               
            end
        end

        % Value changed function: statebutton_selectPolygon
        function statebutton_selectPolygonValueChanged(app, event)
           if app.statebutton_selectPolygon.Value
             % --- PUSHBUTTON: SELECT POLYGON (executes on corresponding button press.)

             %Clear any current ROI
             app.curr_ROI = [];

             %Reset other associated buttons
             app.statebutton_selectCircle.Value = false; %Circle ROI
             app.statebutton_selectCorrPixel.Value = false; %Pixel-correlation --> ROI
             app.statebutton_excludePixelMask.Value = false; %Exclusion indicator

             %Refresh axes
             refresh_Axis(app, 1:4);

             %Get arbitrary polygonal ROI from user input
             app.cellMask = roipoly; %Get logical mask approximation of polygon drawn by user

             %Display patch and corresponding DFF
             [app.curr_ROI, app.curr_cellf] = roiData(app); %Store cellF and patch vertices in memory

           else %Button toggled 'off'
               app.curr_ROI = [];
               app.curr_cellf = [];
               app.cellMask = []; %Clear current ROI data
           end

           %Refresh left & right axes
           refresh_Axis(app, 1:3);

        end

        % Value changed function: statebutton_reserveCellID
        function statebutton_reserveCellIDValueChanged(app, event)
            if app.statebutton_reserveCellID.Value==true
                %Confirm and save placeholder file
                A = questdlg(['Reserve cellID ', app.edit_cellID.Value '?'], 'Reserve Cell ID', 'OK', 'Cancel', 'OK');
                if strcmp(A, 'OK')
                    %Clear current ROI data
                    idx = str2double(app.edit_cellID.Value);
                    app.cellMask = false(size(app.stack,1),size(app.stack,2)); %3D array of logical masks: nY x nX x nROIs
                    app.curr_ROI = [];
                    app.curr_cellf = [];
                    app.reserveCellID(idx) = true;
                    app.excludePixelMask(idx) = false;
                    %save placeholder file
                    app.check_overwrite=false;
                    pushbutton_savetraces_Callback(app);
                end           
            end

        end

        % Value changed function: checkbox_ImportROIs
        function checkbox_ImportROIsValueChanged(app, event)
            if app.checkbox_ImportROIs.Value
                answer = questdlg("Search for ROIs identified in previous session(s)?", "Search Prior ROIs", "Yes", "No", "Yes");
                if answer=="Yes"
                    % Default Params
                    subjID = '';
                    if ~isempty(app.subjectID)
                        subjID = app.subjectID;
                    end
                    data_dir = 'X:\michael\network-batch\rois';
                    frameWidth = 100; %width of viewing box in pixels
                    prompt = {'Subject ID:','Data directory:','Frame width:'};
                    dlgtitle = 'Get Prior ROIs';
                                        
                    definput = {subjID, data_dir, num2str(frameWidth)};
                    answer = inputdlg(prompt, dlgtitle, 1, definput);
                    getPriorROIs(app, answer{1}, answer{2}, str2double(answer{3}));
                end
            end
            
        end

        % Value changed function: edit_subjectID
        function edit_subjectIDValueChanged(app, event)
            app.subjectID = app.edit_subjectID.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            colormap(app.MainFigure, 'parula');
            app.MainFigure.Name = 'cellROI';
            app.MainFigure.SizeChangedFcn = createCallbackFcn(app, @MainFigure_SizeChangedFcn, true);
            app.MainFigure.WindowButtonDownFcn = createCallbackFcn(app, @MainFigure_WindowButtonDownFcn, true);
            app.MainFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MainFigure_WindowScrollWheelFcn, true);
            app.MainFigure.WindowKeyPressFcn = createCallbackFcn(app, @MainFigure_WindowKeyPressFcn, true);
            app.MainFigure.Tag = 'MainFigure';
            app.axes3 = uiaxes(app.MainFigure);
            app.axes3.Tag = 'axes3';
            app.axes2 = uiaxes(app.MainFigure);
            app.axes2.XTick = zeros(1,0);
            app.axes2.YTick = zeros(1,0);
            app.axes2.Tag = 'axes2';
            app.axes1 = uiaxes(app.MainFigure);
            app.axes1.XTick = zeros(1,0);
            app.axes1.YTick = zeros(1,0);
            app.axes1.Tag = 'axes1';
            app.uipanel9 = uipanel(app.MainFigure);
            app.uipanel9.Title = 'Manual ROI Selection';
            app.uipanel9.Tag = 'uipanel9';
            app.statebutton_selectPolygon = uibutton(app.uipanel9, 'state');
            app.statebutton_selectPolygon.ValueChangedFcn = createCallbackFcn(app, @statebutton_selectPolygonValueChanged, true);
            app.statebutton_selectPolygon.Tooltip = 'Press ''z'' to draw a polygon.';
            app.statebutton_selectPolygon.Text = 'Draw Polygon';
            app.text_circRadius = uilabel(app.uipanel9);
            app.text_circRadius.Tag = 'text_circRadius';
            app.text_circRadius.Text = 'Radius:';
            app.edit_circRadius = uieditfield(app.uipanel9, 'text');
            app.edit_circRadius.ValueChangedFcn = createCallbackFcn(app, @edit_circRadius_ValueChangedFcn, true);
            app.edit_circRadius.Tag = 'edit_circRadius';
            app.edit_circRadius.Value = '15';
            app.statebutton_selectCircle = uibutton(app.uipanel9, 'state');
            app.statebutton_selectCircle.ValueChangedFcn = createCallbackFcn(app, @statebutton_selectCircleValueChanged, true);
            app.statebutton_selectCircle.Tooltip = 'Press ''c'' to draw a circle.';
            app.statebutton_selectCircle.Text = 'Draw Circle';
            app.uipanel_XCorr = uipanel(app.MainFigure);
            app.uipanel_XCorr.Title = 'Pixel Correlation';
            app.uipanel_XCorr.Tag = 'uipanel_XCorr';
            app.axes4 = uiaxes(app.uipanel_XCorr);
            app.axes4.XTick = zeros(1,0);
            app.axes4.YTick = zeros(1,0);
            app.axes4.Tag = 'axes4';
            app.statebutton_selectCorrPixel = uibutton(app.uipanel_XCorr, 'state');
            app.statebutton_selectCorrPixel.ValueChangedFcn = createCallbackFcn(app, @statebutton_selectCorrPixel_ValueChanged, true);
            app.statebutton_selectCorrPixel.Tooltip = 'Press ''x'' to select a seed pixel for correlation.';
            app.statebutton_selectCorrPixel.Text = 'Select Pixel';
            app.pushbutton_loadStack = uibutton(app.MainFigure, 'push');
            app.pushbutton_loadStack.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadimagestack_Callback, true);
            app.pushbutton_loadStack.Tag = 'pushbutton_loadStack';
            app.pushbutton_loadStack.Text = 'Load Image Stack';
            app.StackSizeText = uilabel(app.MainFigure);
            app.StackSizeText.Tag = 'text_stackSize';
            app.StackSizeText.Text = 'No image stack loaded.';
            app.edit_XCorrLowerBound = uieditfield(app.MainFigure, 'text');
            app.edit_XCorrLowerBound.ValueChangedFcn = createCallbackFcn(app, @edit_XCorrLowerBound_ValueChangedFcn, true);
            app.edit_XCorrLowerBound.Tag = 'edit_XCorrLowerBound';
            app.edit_XCorrLowerBound.Value = '0.5';
            app.text20 = uilabel(app.MainFigure);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Select if CDF >';
            app.edit_boxWidthXCorr = uieditfield(app.MainFigure, 'text');
            app.edit_boxWidthXCorr.ValueChangedFcn = createCallbackFcn(app, @edit_boxWidthXCorr_Callback, true);
            app.edit_boxWidthXCorr.Tag = 'edit_boxWidthXCorr';
            app.edit_boxWidthXCorr.Value = '50';
            app.text_boxWidthXCorr = uilabel(app.MainFigure);
            app.text_boxWidthXCorr.Tag = 'text_boxWidthXCorr';
            app.text_boxWidthXCorr.Text = 'Box width:';
            app.uipanel_saveCellF = uipanel(app.MainFigure);
            app.uipanel_saveCellF.Title = 'Save Trace';
            app.uipanel_saveCellF.Tag = 'uipanel_saveCellF';
            app.pushbutton_saveCellF = uibutton(app.uipanel_saveCellF, 'push');
            app.pushbutton_saveCellF.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_savetraces_Callback, true);
            app.pushbutton_saveCellF.Tag = 'pushbutton_saveCellF';
            app.pushbutton_saveCellF.Tooltip = 'Press ''s'' to save currently selected ROI.';
            app.pushbutton_saveCellF.Text = 'Save';
            app.text_cellID = uilabel(app.uipanel_saveCellF);
            app.text_cellID.Tag = 'text_cellID';
            app.text_cellID.Text = 'Cell ID#:';
            app.edit_cellID = uieditfield(app.uipanel_saveCellF, 'text');
            app.edit_cellID.ValueChangedFcn = createCallbackFcn(app, @edit_cellID_Callback, true);
            app.edit_cellID.Tag = 'edit_cellID';
            app.edit_cellID.Value = '1';
            app.edit_subjectID = uieditfield(app.uipanel_saveCellF, 'text');
            app.edit_subjectID.ValueChangedFcn = createCallbackFcn(app, @edit_subjectIDValueChanged, true);
            app.edit_subjectID.Tag = 'edit_subjectID';
            app.edit_subjectID.Value = 'Enter subject ID...';
            app.text_subjectID = uilabel(app.uipanel_saveCellF);
            app.text_subjectID.Tag = 'text_subjectID';
            app.text_subjectID.Text = 'Subject ID#:';
            app.pushbutton_delROI = uibutton(app.uipanel_saveCellF, 'push');
            app.pushbutton_delROI.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delROI_Callback, true);
            app.pushbutton_delROI.Tag = 'pushbutton_delROI';
            app.pushbutton_delROI.Tooltip = 'Press ''d'' or ''delete'' key to delete selected ROI.';
            app.pushbutton_delROI.Text = 'Delete';
            app.statebutton_excludePixelMask = uibutton(app.uipanel_saveCellF, 'state');
            app.statebutton_excludePixelMask.ValueChangedFcn = createCallbackFcn(app, @statebutton_excludePixelMask_Callback, true);
            app.statebutton_excludePixelMask.Tag = 'statebutton_excludePixelMask';
            app.statebutton_excludePixelMask.Tooltip = 'Press ''e'' to save and toggle include/exclude state of selected ROI.';
            app.statebutton_excludePixelMask.Text = 'Excl. Pix';
            app.statebutton_reserveCellID = uibutton(app.uipanel_saveCellF, 'state');
            app.statebutton_reserveCellID.ValueChangedFcn = createCallbackFcn(app, @statebutton_reserveCellIDValueChanged, true);
            app.statebutton_reserveCellID.Tooltip = 'Press ''e'' to save and toggle include/exclude state of selected ROI.';
            app.statebutton_reserveCellID.Text = 'Rsv. ID#';
            app.uipanel_loadROIs = uipanel(app.MainFigure);
            app.uipanel_loadROIs.Title = 'Load Saved ROIs';
            app.uipanel_loadROIs.Tag = 'uipanel_loadROIs';
            app.checkbox_loadNeuropilData = uicheckbox(app.uipanel_loadROIs);
            app.checkbox_loadNeuropilData.Tag = 'checkbox_loadNeuropilData';
            app.checkbox_loadNeuropilData.Text = 'Load neuropil data';
            app.pushbutton_loadSavedROIs = uibutton(app.uipanel_loadROIs, 'push');
            app.pushbutton_loadSavedROIs.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadSavedROIs_Callback, true);
            app.pushbutton_loadSavedROIs.Tag = 'pushbutton_loadSavedROIs';
            app.pushbutton_loadSavedROIs.Text = 'Load';
            app.text25 = uilabel(app.uipanel_loadROIs);
            app.text25.Tag = 'text25';
            app.text25.Text = 'ROIs # 1 to';
            app.edit_nROIsToLoad = uieditfield(app.uipanel_loadROIs, 'text');
            app.edit_nROIsToLoad.ValueChangedFcn = createCallbackFcn(app, @edit_nROIsToLoad_Callback, true);
            app.edit_nROIsToLoad.Tag = 'edit_nROIsToLoad';
            app.edit_nROIsToLoad.Value = '1';
            app.checkbox_ImportROIs = uicheckbox(app.uipanel_loadROIs);
            app.checkbox_ImportROIs.ValueChangedFcn = createCallbackFcn(app, @checkbox_ImportROIsValueChanged, true);
            app.checkbox_ImportROIs.Tag = 'checkbox_ImportROIs';
            app.checkbox_ImportROIs.Text = 'Import prior ROIs';
            app.WhiteLevelPanel2 = uipanel(app.MainFigure);
            app.WhiteLevelPanel2.Title = 'White Level';
            app.WhiteLevelPanel2.Tag = 'uipanel8';
            app.WhiteLevelSpinner2 = uispinner(app.WhiteLevelPanel2);
            app.WhiteLevelSpinner2.Step = 0.5;
            app.WhiteLevelSpinner2.Limits = [0 50];
            app.WhiteLevelSpinner2.ValueChangedFcn = createCallbackFcn(app, @WhiteLevelSpinner2ValueChanged, true);
            app.WhiteLevelSpinner2.Value = 1;
            app.WhiteLevelPanel1 = uipanel(app.MainFigure);
            app.WhiteLevelPanel1.Title = 'White Level';
            app.WhiteLevelPanel1.Tag = 'uipanel_whiteLevelAxis1';
            app.WhiteLevelSpinner1 = uispinner(app.WhiteLevelPanel1);
            app.WhiteLevelSpinner1.Step = 0.5;
            app.WhiteLevelSpinner1.Limits = [0 20];
            app.WhiteLevelSpinner1.ValueChangedFcn = createCallbackFcn(app, @WhiteLevelSpinner1ValueChanged, true);
            app.WhiteLevelSpinner1.Value = 1;
            app.uipanel10 = uipanel(app.MainFigure);
            app.uipanel10.Title = 'Neuropil Signals';
            app.uipanel10.Tag = 'uipanel10';
            app.pushbutton_calcNeuropil = uibutton(app.uipanel10, 'push');
            app.pushbutton_calcNeuropil.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calcNeuropil_Callback, true);
            app.pushbutton_calcNeuropil.Tag = 'pushbutton_calcNeuropil';
            app.pushbutton_calcNeuropil.Text = 'Calculate...';
            app.edit_calcNeuropil_Ri = uieditfield(app.uipanel10, 'text');
            app.edit_calcNeuropil_Ri.Tag = 'edit_calcNeuropil_Ri';
            app.edit_calcNeuropil_Ri.Value = '0';
            app.edit_calcNeuropil_Ro = uieditfield(app.uipanel10, 'text');
            app.edit_calcNeuropil_Ro.Tag = 'edit_calcNeuropil_Ro';
            app.edit_calcNeuropil_Ro.Value = '2';
            app.text33 = uilabel(app.uipanel10);
            app.text33.Tag = 'text33';
            app.text33.Text = '(inner)';
            app.text35 = uilabel(app.uipanel10);
            app.text35.Tag = 'text35';
            app.text35.Text = '(outer)';
            app.text36 = uilabel(app.uipanel10);
            app.text36.Tag = 'text36';
            app.text36.Text = {'Cell radii'; 'for mask:'};
            app.pushbutton_recalcCorr = uibutton(app.MainFigure, 'push');
            app.pushbutton_recalcCorr.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_recalcCorr_Callback, true);
            app.pushbutton_recalcCorr.Tag = 'pushbutton_recalcCorr';
            app.pushbutton_recalcCorr.Text = 'Recalc.';
            app.ProjectiontypeButtonGroup1 = uibuttongroup(app.MainFigure);
            app.ProjectiontypeButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @ProjectiontypeButtonGroup1SelectionChanged, true);
            app.ProjectiontypeButtonGroup1.Title = 'Projection type';
            app.MeanButton = uiradiobutton(app.ProjectiontypeButtonGroup1);
            app.MeanButton.Text = 'Mean';
            app.MaxButton = uiradiobutton(app.ProjectiontypeButtonGroup1);
            app.MaxButton.Text = 'Max';
            app.MaxButton.Value = true;
            app.VarianceButton = uiradiobutton(app.ProjectiontypeButtonGroup1);
            app.VarianceButton.Text = 'Variance';
            app.ReferenceButton = uiradiobutton(app.ProjectiontypeButtonGroup1);
            app.ReferenceButton.Text = 'Reference';
            app.ProjectiontypeButtonGroup2 = uibuttongroup(app.MainFigure);
            app.ProjectiontypeButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ProjectiontypeButtonGroup2SelectionChanged, true);
            app.ProjectiontypeButtonGroup2.Title = 'Projection type';
            app.MeanButton2 = uiradiobutton(app.ProjectiontypeButtonGroup2);
            app.MeanButton2.Text = 'Mean';
            app.MaxButton2 = uiradiobutton(app.ProjectiontypeButtonGroup2);
            app.MaxButton2.Text = 'Max';
            app.VarianceButton2 = uiradiobutton(app.ProjectiontypeButtonGroup2);
            app.VarianceButton2.Text = 'Variance';
            app.VarianceButton2.Value = true;
            app.DisplayROIsCheckBox = uicheckbox(app.ProjectiontypeButtonGroup2);
            app.DisplayROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROIsCheckbox_Callback, true);
            app.DisplayROIsCheckBox.Text = 'Display ROIs';
            app.DisplayROIsCheckBox.Value = true;
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellROI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)cellselect_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end