classdef cellROI_migrate_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        pushbutton_recalcCorr       matlab.ui.control.Button
        uipanel10                   matlab.ui.container.Panel
        text36                      matlab.ui.control.Label
        text35                      matlab.ui.control.Label
        text33                      matlab.ui.control.Label
        edit_calcNeuropil_Ro        matlab.ui.control.EditField
        edit_calcNeuropil_Ri        matlab.ui.control.EditField
        pushbutton_calcNeuropil     matlab.ui.control.Button
        uipanel8                    matlab.ui.container.Panel
        text_whiteLevelAxis2        matlab.ui.control.Label
        slider_whiteLevelAxis2      matlab.ui.control.Slider
        uipanel_whiteLevelAxis1     matlab.ui.container.Panel
        text_whiteLevelAxis1        matlab.ui.control.Label
        slider_whiteLevelAxis1      matlab.ui.control.Slider
        uipanel_loadROIs            matlab.ui.container.Panel
        checkbox_ImportROIs         matlab.ui.control.CheckBox
        edit_nROIsToLoad            matlab.ui.control.EditField
        text25                      matlab.ui.control.Label
        pushbutton_loadSavedROIs    matlab.ui.control.Button
        checkbox_loadNeuropilData   matlab.ui.control.CheckBox
        uipanel_saveCellF           matlab.ui.container.Panel
        checkbox_armRepeatSelect    matlab.ui.control.CheckBox
        togglebutton_excludeROI     matlab.ui.control.StateButton
        pushbutton_delROI           matlab.ui.control.Button
        edit_saveNamePrefix         matlab.ui.control.EditField
        edit_cellID                 matlab.ui.control.EditField
        text_cellID                 matlab.ui.control.Label
        pushbutton_saveCellF        matlab.ui.control.Button
        uibuttongroup3              matlab.ui.container.ButtonGroup
        radiobutton_redAxis1        matlab.ui.control.RadioButton
        radiobutton_varAxis1        matlab.ui.control.RadioButton
        radiobutton_maxAxis1        matlab.ui.control.RadioButton
        radiobutton_meanAxis1       matlab.ui.control.RadioButton
        uibuttongroup1              matlab.ui.container.ButtonGroup
        checkbox_showROIs           matlab.ui.control.CheckBox
        radiobutton_varAxis2        matlab.ui.control.RadioButton
        radiobutton_maxAxis2        matlab.ui.control.RadioButton
        radiobutton_meanAxis2       matlab.ui.control.RadioButton
        text_boxWidthXCorr          matlab.ui.control.Label
        edit_boxWidthXCorr          matlab.ui.control.EditField
        text20                      matlab.ui.control.Label
        edit_XCorrLowerBound        matlab.ui.control.EditField
        text_stackSize              matlab.ui.control.Label
        pushbutton_loadStack        matlab.ui.control.Button
        uipanel_XCorr               matlab.ui.container.Panel
        pushbutton_selectCorrPixel  matlab.ui.control.Button
        axes4                       matlab.ui.control.UIAxes
        uipanel_shiftXY             matlab.ui.container.Panel
        text_shiftY                 matlab.ui.control.Label
        text_shiftX                 matlab.ui.control.Label
        slider_shiftY               matlab.ui.control.Slider
        slider_shiftX               matlab.ui.control.Slider
        uipanel9                    matlab.ui.container.Panel
        pushbutton_selectCircle     matlab.ui.control.Button
        edit_circRadius             matlab.ui.control.EditField
        text_circRadius             matlab.ui.control.Label
        pushbutton_selectPolygon    matlab.ui.control.Button
        axes1                       matlab.ui.control.UIAxes
        axes3                       matlab.ui.control.UIAxes
        axes2                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function closeReq_refFig(app, ~, ~, handles)
            handles = guidata(handles.figure1);
            delete(handles.ref_fig.figure);
            handles = rmfield(handles,'ref_fig');
            %handles = delete_refFig(handles); %Close ref fig
            refresh_Axis(app, 1:2,handles);
        end
        
        function handles = delete_refFig(app, handles)
            %Clear any prior reference figure
            if isfield(handles,'ref_fig') && isfield(handles.ref_fig,'figure')
                if isvalid(handles.ref_fig.figure)
                    handles.ref_fig.figure.CloseRequestFcn =...
                        {@app.closeReq_refFig,handles}; %Update handles before close request.
                    delete(handles.ref_fig.figure);
                end
                handles = rmfield(handles,'ref_fig');
            end
        end
        
        function poly = getNpPoly(app, mask)
            %--- GENERATE ANNULUS REPRESENTING NEUROPIL MASK --------------------------
            %Generate polygon representation of neuropil mask (graphics object)
            
            B = bwboundaries(mask);
            warning('off','MATLAB:polyshape:repairedBySimplify');
            if size(B,1)==1 %Eg if annulus is interrupted and has no holes
                poly = polyshape(B{1}(:,2),B{1}(:,1)); %X-,Y-coordinates
            else
                nPoints = cellfun(@(C) size(C,1),B)'; %Number of points in each polygon
                start_idx = cumsum([0 nPoints+1])+1; %Starting idx for each series of points (series separated by NaN)
                X = NaN(sum(nPoints)+numel(nPoints)-1,1); %Initialize vector with space for NaN between each series of points
                Y = X;
                for i=1:numel(nPoints)
                    X(start_idx(i):start_idx(i)+nPoints(i)-1) = B{i}(:,2);
                    Y(start_idx(i):start_idx(i)+nPoints(i)-1) = B{i}(:,1);
                end
                poly = polyshape(X,Y);
            end
        end
        
        function roiData = getPriorROIs(app, roi_dirs, master_filename)
            %Get all ROIs from previous sessions
            
            
            nROIs = 0; %Initialize counter
            for i = 1:numel(roi_dirs)
                file_list = dir(fullfile(roi_dirs{i},'*cell*.mat'));
            
                for j = 1:numel(file_list)
                    S = load(fullfile(roi_dirs{i},file_list(j).name));
                    fields = fieldnames(S);
                    for k = 1:numel(fields)
                        roiData.(fields{k}){nROIs+1} = S.(fields{k});
                    end
                    [startIdx,endIdx] = regexp(file_list(j).name,'cell\d{3}');
                    roiData.cell_ID{nROIs+1} = file_list(j).name(startIdx+4:endIdx);
            
                    [~,name,ext] = fileparts(roi_dirs{i});
                    temp = strjoin({name,ext},'');
                    roiData.fname_stack{nROIs+1} = temp(5:end); %Remove prefix: 'ROI_'
                    nROIs = nROIs+1;
                end
            end
            
            if isfile(master_filename)
                save(master_filename,'-STRUCT','roiData','-append');
            else, save(master_filename,'-STRUCT','roiData');
            end
        end
        
        function img = getProjection(app, axisID, handles)
            %--- GET PROJECTION ASSOCIATED WITH EACH SET OF AXES -----------------------
            
            
            %Find active radio button and get white level for specified axis
            if axisID == 1
                radioOn = find([handles.radiobutton_meanAxis1.Value,...
                    handles.radiobutton_maxAxis1.Value,...
                    handles.radiobutton_varAxis1.Value,...
                    handles.radiobutton_redAxis1.Value]);
                whiteLevel = handles.slider_whiteLevelAxis1.Value; %White levels
            else
                radioOn = find([handles.radiobutton_meanAxis2.Value,...
                    handles.radiobutton_maxAxis2.Value,...
                    handles.radiobutton_varAxis2.Value,...
                    false]); %No reference on Axes 2 (right)
                whiteLevel = handles.slider_whiteLevelAxis2.Value; %White levels
            end
            
            %Get specified projection
            switch radioOn
                case 1, img = handles.mean_proj;
                case 2, img = handles.max_proj;
                case 3, img = handles.var_proj;
                case 4
                    shift = [-handles.slider_shiftY.Value handles.slider_shiftX.Value];
                    img = circshift(handles.red_proj, shift);  %Apply translation if needed
            end
            %Adjust White Level
            img = whiteLevel .* (img./max(img(:))) * 256;
        end
        
        function save_name = getSaveName(app, handles, idx)
            %Generate filename for save
            
            
            if ~strcmp(handles.edit_saveNamePrefix.String, 'Prefix for save...') %If other than default
                save_name = fullfile(strcat(handles.edit_saveNamePrefix.String,...
                    '_cell',sprintf('%03d',idx),'.mat'));
            else
                save_name = fullfile(strcat('cell',sprintf('%03d',idx),'.mat')); %If no prefix entered (default)
            end
            %Check if already used and if so, get user input: overwrite or generate new filename
            if exist(fullfile(handles.save_dir,save_name),'File') && handles.check_overwrite
                A = questdlg(['Overwrite ',save_name,'?'],'Overwrite ROI File','Overwrite.','New Filename.','Overwrite.');
                if strcmp(A,'New Filename.')
                    [handles,idx] = nxtCellID(app, handles); %Increment cell ID for save
                    save_name = getSaveName(app, handles,idx);
                end
            end
        end
        
        function handles = initUserData(app, handles)
            %--- INITIALIZE USER DATA STRUCTURE (struct 'handles') --------------------
            
            
            handles.cmap = colormap(repmat(linspace(0,1,256)',1,3)); %Gray color map for imaging data
            handles.roi = {}; %Cell array containing vertices of all ROIs
            handles.cellf = {}; %Cell array containing mean cellular fluorescence for each ROI
            handles.curr_ROI = []; %Vertices of current ROI patch
            handles.curr_cellf = []; %Cellular fluorescence from current ROI
            handles.cellMask = false(size(handles.stack,1),size(handles.stack,2)); %Logical mask for the currently selected ROI
            handles.cellMasksAll = false(size(handles.stack,1),size(handles.stack,2)); %3D array to store all cell masks
            handles.save_names = {}; %Cell array of strings; filenames for save
            handles.check_overwrite = true; %Check before overwriting file on save
            handles.excludeROI = false; %Logical vector indicating excluded regions (excluded from neuropil and flagged in ROI file)
            handles.neuropilf = {}; %Cell array for each cell's neuropil signal as a function of time
            handles.npPoly = polyshape(); %Polygon vertices for the neuropil 'subtractmask' for each ROI polyshape
            
            f_list = dir(fullfile(handles.save_dir,'*cell*.mat'));
            for i = 1:numel(f_list) %Number of ROIs in data dir.
                idx = str2double(f_list(i).name(end-6:end-4)); %Get three-digit cell ID
                handles.save_names{idx} = f_list(i).name; %Populate cell array of save names
            end
        end
        
        function handles = loadImageStack(app, handles)
            % ---Load Image Stack------------------------------------------------------
            %Load stack from file or input argument and generate associated projections
            
            
            %Check for saved data file roiData.mat
            handles.save_dir = fullfile(handles.pathname,...
                strcat('ROI_',handles.filename));                       %Path for ROI directory
            roiData_fname = fullfile(handles.save_dir,'roiData.mat');
            if exist(roiData_fname,'file')
                S = load(roiData_fname,'stack','max_proj','mean_proj','var_proj');
                disp(['Loading saved data from ' roiData_fname ':']);
            
                fields = fieldnames(S);
                disp(fields);
                for i = 1:numel(fields)
                    handles.(fields{i}) = S.(fields{i}); %fields = {'stack','mean_proj','var_proj','max_proj',...};
                end
            else
                mkdir(handles.save_dir); %Setup subdir for saving files
                S = struct('filename',handles.filename);
                save(roiData_fname,'-struct','S','-v7.3');
            end
            
            %Load stack using custom function loadtiffseq.m
            if ~isfield(handles,'stack')
                disp(['Loading ' handles.pathname handles.filename '...']); tic
                stack = double(loadtiffseq(fullfile(handles.pathname,handles.filename))); toc
                handles.stack = stack-min(stack(:)); %Recenter so all values are positive
            end
            
            %Calculate mean, max, and variance projections to aid in ROI selection
            if ~isfield(handles,'var_proj'), tic;
                handles.text_stackSize.String = 'Getting projections...';
                disp('Getting mean projection...');
                handles.mean_proj = mean(handles.stack,3);
                disp('Getting variance projection...');
                handles.stack = permute(handles.stack,[3,1,2]); %Time-dimension to D1 to speed calculation (tested 3-20x faster)
                handles.var_proj = squeeze(var(handles.stack,0,1));
                handles.stack = permute(handles.stack,[2,3,1]); %Time-dimension back to D3
                disp('Getting maximum projection...');
                handles.max_proj = max(handles.stack,[],3); toc
                %Save projections in roiData.mat
                disp(['Saving all projections in ' roiData_fname '...']);
                S = struct('mean_proj',handles.mean_proj,'var_proj',handles.var_proj,...
                    'max_proj',handles.max_proj);
                save(roiData_fname,'-struct','S','-append'); disp('Done.');
            end
            
            %Project data onto Axes 1 (left) & 2 (right)
            for i=1:2 %Check radio buttons and get specified projection
                handles.img{i} = getProjection(app, i,handles); %img = getProjection(axisID,handles)
            end
            
            %Initialize user data structure and populate text/edit fields
            handles = initUserData(app, handles);
            handles.edit_nROIsToLoad.String = num2str(numel(handles.save_names)); %Last cell ID
            handles.edit_cellID.String = '1'; %Cell ID for save
            handles.figure1.Name = ['cellROI 2.0     ' handles.filename];
            [nY,nX,nZ] = size(handles.stack);
            handles.text_stackSize.String = [num2str(nZ) ' frames of ' num2str(nX) 'x' num2str(nY) ' loaded.'];
        end
        
        function [handles, idx] = nxtCellID(app, handles)
            %--- INCREMENT CELL_ID FOR SAVE -------------------------------------------
            
            
            idx = find(cellfun(@isempty,handles.save_names),1,'first'); %Find first unused savename
            if isempty(idx)
                idx = numel(handles.save_names)+1;
            end
            handles.edit_cellID.String = num2str(idx);
        end
        
        function plotROI_refFig(app, ~, ~, handles)
            %Generate reference image for current ROI
            
            
            idx = handles.ref_fig.curr_idx;
            cla;
            imagesc(handles.ref_fig.pix{idx}); hold on
            plot(handles.ref_fig.roi{idx}(:,1),...
                handles.ref_fig.roi{idx}(:,2),'r');
            text(1,3,handles.ref_fig.date{idx}); %Tag img with session date
            
            ax = gca;
            ax.Position = [0,0,1,1]; %Eliminate white space
            ax.YTickLabel = []; ax.XTickLabel = [];
            axis square;
            
            %Increment session index
            i = handles.ref_fig.curr_idx;
            if i < handles.ref_fig.end_idx
                i = i+1;
            else
                i = 1;
            end
            handles.ref_fig.curr_idx = i;
            handles.ref_fig.figure.WindowButtonDownFcn = {@app.plotROI_refFig,handles};
        end
        
        function handles = refFig_priorROIs(app, cell_ID, width, handles)
            %--- CREATE REFERENCE IMAGES FROM PRIOR ROIS ------------------------------
            % Generate interactive figure containing reference images from prior sessions
            
            
            %Clear any prior reference figure
            handles = delete_refFig(app, handles);
            
            %Retrieve image segment from each prior session containing ROI
            load(handles.masterROIs_filename,'frameSegment','bw'); %frameSegment is a struct generated by script screenROIs.m
            
            if ~exist('frameSegment') %Alert Message
                warndlg('No reference image for current ROI. Always update longitudinal data by running the script "screenROIs.m" before starting a new ROI selection session.','Warning');
                return
            end
            
            idx = find(strcmp({frameSegment.cell_ID}, cell_ID),1,'first'); %cell_ID = '001'
            frameSegment = frameSegment(idx); %Abridge struct to include only current cell
            [nY,~] = size(bw{1});
            
            %Warn if ROI not found in prior sessions
            if isempty(idx)
                warning('No prior sessions found containing this cell_ID. Check directory structure, then run script "screenROIs.m" to obtain reference images.');
                return
            end
            
            %Figure position
            Y = frameSegment.centroid{1}(2);
            if Y < 0.5*nY
                fig_pos = [100,100,width,width];
            else fig_pos = [100,800,width,width];
            end
            
            %Image ROI and surrounding pixels for each session
            handles.ref_fig = frameSegment; %Store frames to advance on mouse click
            handles.ref_fig.curr_idx = 1; %Initialize image index
            handles.ref_fig.end_idx = numel(frameSegment.pix); %Idx of last session (chronological)
            
            fig = figure('Visible','on','NumberTitle','off','WindowStyle','normal'); %Create figure window
            fig.Position = fig_pos;
            fig.MenuBar = 'none';
            fig.ToolBar = 'none';
            fig.Name = 'Reference Image';
            handles.ref_fig.figure = fig; %Store figure for later reference
            
            handles.ref_fig.figure.WindowButtonDownFcn =...
                {@app.plotROI_refFig,handles}; %On mouse click, create ROI ref image from next session
            handles.ref_fig.figure.WindowKeyPressFcn =...
                {@app.refocus_mainFig,handles}; %Focus on main GUI window if user presses a key
            handles.ref_fig.figure.CloseRequestFcn =...
                {@app.closeReq_refFig,handles}; %Focus on main GUI window if user presses a key
            
            plotROI_refFig(app, [],[],handles);
            txt_y = size(frameSegment.pix{1},1) - 3; %y-position of message
            text(1,txt_y,{'Left click on image'; 'advances to next session.'},'Color','r');
        end
        
        function refocus_mainFig(app, ~, eventdata, handles)
            figure(handles.figure1);
            handles = guidata(handles.figure1);
            figure1_WindowKeyPressFcn(handles.figure1,eventdata,handles);
        end
        
        function refresh_Axis(app, axis_ID, handles)
            %--- REFRESH AXES ---------------------------------------------------------
            
            
            ax = {'axes1','axes2','axes3','axes4'}; %Fieldnames for all axes in handles structure
            currIdx = 0; %Current cell ID
            for i = axis_ID %Set of int axes_IDs passed to function
            
                %For each axis
                cla(handles.(ax{i})); %Clear axes
                handles.(ax{i}).TickLength = [0.001 0.01];
                handles.(ax{i}).FontSize = 10;
                hold(handles.(ax{i}),'on'); %Hold on
            
                %Axes1 and Axes2 (FOV axes)
                if ismember(ax{i},{'axes1','axes2'})
                    image(handles.(ax{i}),handles.img{i});  %Refresh image
                    colormap(handles.(ax{i}),handles.cmap);
                    axis(handles.(ax{i}),'off','equal','ij');
            
                    if handles.checkbox_showROIs.Value
                        %Refresh ROI patches
                        for j = find(~cellfun(@isempty,handles.roi)) %Refresh saved ROIs
                            roi = handles.roi{j};
                            p = patch(handles.(ax{i}),'XData',roi(:,1),'YData',roi(:,2),...
                                'FaceColor','y','EdgeColor','none','FaceAlpha',0.2); %Display ROI on specified axes
                            set(p,'ButtonDownFcn',{@app.selectROI,handles},...
                                'PickableParts','all','HitTest','on');
                            if isequal(handles.roi{j},handles.curr_ROI)
                                p.FaceColor = 'r';                 %Highlight current ROI in red;
                                currIdx = j; %Currently selected cell ID
                                text(handles.axes1,1,3,'Right click to deselect current ROI.','Color','r');
                            elseif handles.excludeROI(j)
                                p.FaceColor = 'k'; p.FaceAlpha = 0.5; %Excluded rois in black
                            end
                            %Make all others transparent while assigning longitudinal ROI
                            if isfield(handles,'ref_fig') && isfield(handles.ref_fig,'figure')...
                                    && isvalid(handles.ref_fig.figure)...
                                    && ~isequal(handles.roi{j},handles.curr_ROI)
                                p.FaceAlpha = 0.05;
                            end
                        end
                        if ~currIdx && ~isempty(handles.curr_ROI) %If newly drawn ROI
                            patch(handles.(ax{i}),'XData',handles.curr_ROI(:,1),'YData',handles.curr_ROI(:,2),...
                                'FaceColor','r','EdgeColor','none','FaceAlpha',0.4); %Display ROI on specified axes
                        end
                        %Refresh neuropil masks if needed
                        for j = find(~cellfun(@isempty,{handles.npPoly.Vertices})) %Refresh saved neuropil masks
                            plot(handles.(ax{i}),handles.npPoly(j),...
                                'FaceColor','c','EdgeColor','none','FaceAlpha',0.2);
                        end
                    end
            
                elseif strcmp(ax{i},'axes3') %Refresh axes3 (dF/F plot)
                    cellf = NaN(size(handles.stack,3),1);
                    if ~isempty(handles.curr_cellf)
                        cellf = handles.curr_cellf;
                        cellf = cellf - min(cellf); %Offset cellular fluorescence data by min(F) for approx dF/F0; F0 should be positive
                    end
            
                    plot(handles.axes3,(1:length(cellf)),... %Plot cell dF/F
                        (cellf-median(cellf)) / median(cellf),'k','LineWidth',1);
                    axis(handles.axes3,'tight');
                    xlim(handles.axes3,[0 length(cellf)]);
                    if currIdx && ~isempty(handles.neuropilf) %Plot neuropil signal as dF/F
                        npf = handles.neuropilf{currIdx};
                        plot(handles.axes3,(1:length(npf)),(npf-median(npf)) / median(cellf),'c'); %Same scale as cellF
                    end
            
                elseif strcmp(ax{i},'axes4') %Refresh axes4 (Pearson's R histogram)
                    %Nothing yet!
                end
            end
        end
        
        function handles = resizeROI(app, handles, keypressed)
            %---RESIZE ROI-------------------------------------------------------------
            
            %Modify ROI by adding/subtracting pixels to top, bottom, right, left
            if strcmp(keypressed,'uparrow')
                dir = 'post';
                val = true;
            elseif strcmp(keypressed,'downarrow')
                dir = 'pre';
                val = false;
            end
            
            if isempty(handles.cellMask)
                [m,n,~] = size(handles.stack);
                BW = poly2mask(handles.curr_ROI(:,1),handles.curr_ROI(:,2),m,n);
            else
                BW = handles.cellMask;
                top = (padarray(logical(diff(BW,1,1)),[1,0],0,dir)); %Find edges from TOP and set logical idx to 1
                bottom = (flip(padarray(logical(diff(flip(BW,1),1,1)),[1,0],0,dir),1)); %BOTTOM
                left = (padarray(logical(diff(BW,1,2)),[0,1],0,dir)); %LEFT
                right = (flip(padarray(logical(diff(flip(BW,2),1,2)),[0,1],0,dir),2)); %RIGHT
                BW(top|bottom|left|right) = val;
            end
            handles.cellMask = BW;
            [handles.curr_ROI, handles.curr_cellf] = roiData(app, handles);
        end
        
        function [roi, cellf] = roiData(app, handles)
            %--- GET ROI VERTICES AND ASSOCIATED CELLULAR FLUORESCENCE -----------------
            
            
            %Display current ROI as patch on left and right axes
            bounds = bwboundaries(handles.cellMask); %Boundaries of logical mask for current ROI
            roi = [bounds{1}(:,2) bounds{1}(:,1)]; %Boundary coordinates in ij
            
            %Get cellF as mean per-pixel fluorescence and plot the dF/F on bottom panel
            cellf = NaN(size(handles.stack,3),1);
            for i=1:size(handles.stack,3)
                cellf(i) = sum(sum(handles.stack(:,:,i).*double(handles.cellMask))); %Pre-20018b syntax for back-compatibility
            end
            cellf = cellf/sum(sum(handles.cellMask));
        end
        
        function saveRefImg(app, handles)
            %--- SAVE FULL FOV REFERENCE IMAGE WITH ROIS ------------------------------
            %   -Can be used eg for longitudinal imaging
            
            %Plot all ROIs
            f = figure('Visible','off','Position',[100, 100, size(handles.stack,1), size(handles.stack,2)]);
            ax = copyobj(handles.axes2,f);
            ax.YLim = [0 size(handles.stack,1)];
            ax.XLim = [0 size(handles.stack,2)];
            %Label with cellIDs
            for i = 1:numel(handles.save_names)
                if isempty(handles.save_names{i})
                    continue
                else
                saveNum = join(string(regexp(handles.save_names{i},'\d','match')),'');
                pos = mean(handles.roi{i});
                text(pos(1),pos(2),saveNum,"HorizontalAlignment","center","Color",'c');
                end
            end
            %Write image to file
            set(ax,'Units','pixels','Position',[0, 0, ax.YLim(2), ax.XLim(2)],'LooseInset',[0,0,0,0]);
            img = getframe(ax);
            imwrite(img.cdata,fullfile(handles.save_dir,'ROI_Image.tif'),'tif');
        end
        
        function selectROI(app, hObject, ~, handles)
            %--- SELECT ROI -------------------------------------------------------------
            
            
            handles = guidata(handles.figure1); %Update handles struct in case changed since object created.
            for i=1:numel(handles.roi)
                if isequal(hObject.Vertices,handles.roi{i}), idx = i; end %Find the index of clicked ROI
            end
            radius = sqrt(polyarea(hObject.XData,hObject.YData)/pi); %Approximate radius
            
            %Highlight current ROI
            handles.curr_ROI = handles.roi{idx}; %Make these vertices the current ROI
            handles.curr_cellf = handles.cellf{idx}; %Set corresponding cellF as current cellF
            handles.edit_cellID.String = num2str(idx); %Make ROI idx current
            handles.togglebutton_excludeROI.Value = handles.excludeROI(idx); %Update state of toggle button
            
            %Update logical mask for current ROI
            handles.cellMask = handles.cellMasksAll(:,:,idx);
            
            %Display approximate radius in pixels
            R = ceil(radius*2)/2; %Radius with resolution of 0.5 pixels
            handles.edit_circRadius.String = num2str(R);
            
            %Display distribution of mean pixel intensity for current ROI vs. surround
            [Y,X] = find(handles.cellMasksAll(:,:,idx));
            centroid.X = mean(X);
            centroid.Y = mean(Y);
            R(1) = str2double(handles.edit_calcNeuropil_Ri.String); %Number of cell radii for inner diameter
            R(2) = str2double(handles.edit_calcNeuropil_Ro.String); %Number of cell radii for outer diameter
            for j = 1:2
                [X,Y] = meshgrid(1:size(handles.stack,2),1:size(handles.stack,1)); %Get grids within R(j) cell radii of centroid
                circle{j} = sqrt((X - centroid.X).^2 + (Y - centroid.Y).^2)...
                    <= R(j) * radius;   %Inner or outer radius of annulus in units of cell radius
            end
            %Get logical masks for current ROI, all other ROIs, and surround
            temp = true(size(handles.cellMasksAll,3),1);
            temp(idx) = false; %Idx for all except current cell mask
            otherCellMask = logical(sum(handles.cellMasksAll(:,:,temp),3)); %Mask for all saved ROIs except current
            cellMask = handles.cellMasksAll(:,:,idx) & ~otherCellMask;
            surroundMask = circle{2} & ~(circle{1} | cellMask | otherCellMask); %Mask for circular region surrounding ROI, excluding inner circle and somata mask
            
            %Plot histogram of max pixel intensity
            cla(handles.axes4);
            hold(handles.axes4,'on');
            F_cellMask = mean(handles.max_proj(cellMask)); %Grand mean fluorescence within current ROI
            F_surround = handles.max_proj(surroundMask);   %Mean fluorescence for each pixel in surround
            [counts,edges] = histcounts(F_surround);
            bar(handles.axes4,edges(1:end-1),counts,'histc');
            plot(handles.axes4,[F_cellMask F_cellMask],[0 1.1*max(counts)],'r','LineWidth',1);
            text(handles.axes4,mean(xlim),max(ylim),{'Hist of max intensity for surrounding pixels';'line: current ROI'});
            
            temp = [edges,F_cellMask];
            rel_F = (F_cellMask - mean(F_surround))/mean(F_surround);
            text(handles.axes4,max(temp),max(counts),num2str(rel_F,3));
            xlim(handles.axes4,[min(temp),1.2*max(temp)]);
            if sum(counts)>0
            ylim(handles.axes4,[0,1.1*max(counts)]); %ylim set to 1.1 * maximum count
            end
            %-----------
            
            %If checkbox 'Import prior ROIs' checked, popup figure with prior ROIs
            if handles.checkbox_ImportROIs.Value
                cell_ID = sprintf('%03d',str2double(handles.edit_cellID.String)); %format of cell ID from .mat files, eg '004'
                width = 200;
                handles = refFig_priorROIs(app, cell_ID, width, handles); %Bring up figure with images of ROI from prior sessions
            end
            
            guidata(hObject,handles); %Save userdata
            refresh_Axis(app, (1:3),handles);
        end
        
        function handles = transferROIs(app, data_dir, handles)
            %--- TRANSFER ROIS FROM ADDITIONAL SESSION(S) -----------------------------
            %ROI dir(s) to load must be in same directory as current ROI dir.
            
            
            subject_ID = char(inputdlg('Enter search filter (eg experimental subject ID).','Find Prior ROIs')); %User enters subject name, or other string to use as filter for dirs to search
            
            temp = dir(fullfile(data_dir,['*' subject_ID '*']));
            for i = 1:numel(temp)
                dirs.sessions{i} = fullfile(data_dir,temp(i).name);
            end
            
            % MAT file to save all ROIs from a given subject
            handles.masterROIs_filename = fullfile(data_dir,['master_rois_' subject_ID '.mat']); %Master ROI file
            
            for i=1:numel(dirs.sessions)
                temp = dir(fullfile(dirs.sessions{i},'ROI*.tif'));
                temp = temp(temp.isdir); %Get only the directories
                if ~isempty(temp)
                    dirs.roi{i} = fullfile(dirs.sessions{i},temp.name);
                end
            end
            
            %Call getPriorROIs() to aggregate all ROIs from previous sessions
            roiData = getPriorROIs(app, dirs.roi,handles.masterROIs_filename);
            cell_IDs = unique(roiData.cell_ID);
            
            %Load first chronological instance of each ROI
            disp(['Loading ' num2str(numel(cell_IDs)) ' ROIs saved from previous sessions...']);
            for i = 1:numel(cell_IDs)
                temp_idx = find(ismember(roiData.cell_ID, cell_IDs{i})); %All indices from masterfile for current cell
                save_name = strcat('cell', cell_IDs{i}, '.mat');
            
                %ROIs from first session in which identified (unless excluded)
                idx = temp_idx(find(~cellfun(@isempty,roiData.cellf(temp_idx)),1,'first'));
                if ~exist(fullfile(handles.save_dir, save_name),'file') && ~isempty(idx)
                    S = struct('cellf', [], 'bw', roiData.bw{idx}); %Store cell mask and dummy var for cellf in a structure
                    save(fullfile(handles.save_dir, save_name),'-struct','S'); %Save as eg 'cell004'
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellselect_OpeningFcn(app, varargin)
            % --- Executes just before GUI is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            %Default command line output
            handles.output = hObject;
            
            %Allow passage of stack, mean_proj, var_proj, max_proj, as fields of struct as INPUT ARG #1
            if numel(varargin)>0
                if isinteger(varargin{1})
                    handles.stack = varargin{1};
                elseif isstruct(varargin{1})
                    fields = fieldnames(varargin{1});
                    for i = 1:numel(fields)
                        handles.(fields{i}) = varargin{1}.(fields{i});
                    end %fields = {'stack','mean_proj','var_proj','max_proj','filename'};
                end
            end
            
            if all(isfield(handles,{'stack','filename','pathname'}))
                handles = loadImageStack(app, handles);
                refresh_Axis(app, (1:4),handles); %Refresh left & right axes
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_showROIs
        function checkbox_showROIs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %If using reference figs for longitudinal ROI selection
            if isfield(handles,'ref_fig') && isfield(handles.ref_fig,'figure')
                figure(handles.ref_fig.figure); %refocus on ref fig
            end
            %In any case, refresh image axes to toggle display/hide ROIs
            refresh_Axis(app, 1:2,handles);
        end

        % Value changed function: edit_XCorrLowerBound
        function edit_XCorrLowerBound_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pushbutton_recalcCorr_Callback(handles.pushbutton_recalcCorr, eventdata, handles)
            uicontrol(handles.pushbutton_recalcCorr);
        end

        % Value changed function: edit_boxWidthXCorr
        function edit_boxWidthXCorr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pushbutton_recalcCorr_Callback(handles.pushbutton_recalcCorr, eventdata, handles)
            uicontrol(handles.pushbutton_recalcCorr);
        end

        % Value changed function: edit_cellID
        function edit_cellID_Callback(app, event)
            %--- EDIT and CHECK BOXES (mostly unused callback functions) ----------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Highlight current ROI
            idx = str2double(handles.edit_cellID.String); %Make ROI idx current
            if size(handles.roi,2)>=idx && ~isempty(handles.roi{idx})
                handles.curr_ROI = handles.roi{idx}; %Make these vertices the current ROI
                handles.curr_cellf = handles.cellf{idx}; %Set corresponding cellF as current cellF
                handles.togglebutton_excludeROI.Value = handles.excludeROI(idx); %Update state of toggle button
            else
                handles.curr_ROI = []; %Make these vertices the current ROI
                handles.curr_cellf = []; %Set corresponding cellF as current cellF
                handles.togglebutton_excludeROI.Value = 0; %Update state of toggle button
            end
            
            guidata(hObject,handles); %Save userdata
            refresh_Axis(app, (1:3),handles);
        end

        % Value changed function: edit_circRadius
        function edit_circRadius_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pushbutton_selectCircle_Callback(handles.pushbutton_selectCircle, eventdata, handles)
            uicontrol(handles.pushbutton_selectCircle);
        end

        % Value changed function: edit_nROIsToLoad
        function edit_nROIsToLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pushbutton_loadSavedROIs_Callback(handles.pushbutton_loadSavedROIs, eventdata, handles)
            uicontrol(handles.pushbutton_loadSavedROIs);
        end

        % Size changed function: figure1
        function figure1_SizeChangedFcn(app, event)
            %--- SIZE CHANGED FUNCTION (executes when figure window is resized.)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try refresh_Axis(app, 1:4,handles); end
        end

        % Window button down function: figure1
        function figure1_WindowButtonDownFcn(app, event)
            %--- WINDOW BUTTONDOWN FUNCTION (executes on mouse click with focus on cellROI.fig) ---
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if strcmp(handles.figure1.SelectionType,'alt')
                handles.curr_ROI = [];
                handles.cellMask = [];
                handles.togglebutton_excludeROI.Value = handles.togglebutton_excludeROI.Min; %Toggle button off
            
                %If using reference figs for longitudinal ROI selection
                if isfield(handles,'ref_fig') && isfield(handles.ref_fig,'figure')
                    handles = delete_refFig(app, handles); %Close ref fig
                end
                [handles, ~] = nxtCellID(app, handles); %Increment cell ID
            
                guidata(hObject,handles);
                try refresh_Axis(app, 1:4,handles); end %Try is used to avoid error in case user clicks before loading imaging data
            end
        end

        % Window key press function: figure1
        function figure1_WindowKeyPressFcn(app, event)
            %--- KEYPRESS FUNCTIONS (executes on keypress with focus on cellROI.fig) ---
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            keyPressed = eventdata.Key; %Determine the key that was pressed
            if strcmpi(keyPressed,'s') %Save ROI and associated cellF
                pushbutton_savetraces_Callback(handles.pushbutton_saveCellF,[],handles);
            elseif strcmpi(keyPressed,'c') %Draw ROI with 'draw circle' callback function
                pushbutton_selectCircle_Callback(handles.pushbutton_selectCircle, [], handles);
            elseif strcmpi(keyPressed,'x') %Draw ROI with pixel correlation callback function
                pushbutton_selectCorrPixel_Callback(handles.pushbutton_selectCorrPixel, [], handles);
            elseif strcmpi(keyPressed,'z') %Draw ROI with 'draw polygon callback function
                pushbutton_selectPolygon_Callback(handles.pushbutton_selectPolygon, [], handles);
            elseif strcmpi(keyPressed,'e') %Draw ROI with previous pushbutton callback function
                handles.togglebutton_excludeROI.Value = abs(handles.togglebutton_excludeROI.Value-1); %Toggle button on/off
                togglebutton_excludeROI_Callback(handles.togglebutton_excludeROI, [], handles);
            elseif strcmpi(keyPressed,'delete') || strcmpi(keyPressed,'d')%Delete ROI data
                pushbutton_delROI_Callback(handles.pushbutton_delROI,[],handles); %DELETE ROI
            elseif (strcmpi(keyPressed,'uparrow') || strcmpi(keyPressed,'downarrow'))...
                    && all(strcmp(eventdata.Modifier,'shift'))
                handles = resizeROI(app, handles,keyPressed); %Change ROI area by one pixel on each side
                guidata(hObject,handles);
                refresh_Axis(app, (1:3),handles);  %Refresh left & right axes
            end
        end

        % Window scroll wheel function: figure1
        function figure1_WindowScrollWheelFcn(app, event)
            % --- SCROLL-WHEEL FUNCTION (Executes on scroll wheel click while the figure is in focus)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %If ROI selected and Pixel-correlation function active
            scrollClick = eventdata.VerticalScrollCount;
            if ~isempty(handles.curr_ROI)
                %&& all(isfield(handles,["recalcCorr_active","selectCircle_active"])) ~isempty(handles.curr_ROI)
                if handles.recalcCorr_active
                    thresh = str2double(get(handles.edit_XCorrLowerBound,'String'));
                    thresh = max([min([thresh+0.05*scrollClick, 1]),0]);
                    set(handles.edit_XCorrLowerBound,'String',num2str(thresh));
                    pushbutton_recalcCorr_Callback(handles.pushbutton_recalcCorr, eventdata, handles)
                    %uicontrol(handles.pushbutton_recalcCorr); %Focus on button (clear edit cursor)
            
                elseif handles.selectCircle_active
                    %Increment radius
                    r = str2double(get(handles.edit_circRadius,'String')) + scrollClick;
                    set(handles.edit_circRadius,'String',num2str(r));
                    %Determine the approx. circular logical mask
                    X = mean(handles.curr_ROI(:,1)); %XY coordinates of centroid of circular ROI
                    Y = mean(handles.curr_ROI(:,2));
                    [rr,cc] = meshgrid(1:size(handles.stack,1));
                    handles.cellMask = sqrt((rr-X).^2+(cc-Y).^2)<=r; %Get grids within circle radius
                    %Store cellF and patch vertices in memory
                    [handles.curr_ROI, handles.curr_cellf] = roiData(app, handles);
                    guidata(hObject, handles); %Save user data
                    refresh_Axis(app, (1:3),handles); %Display patch and corresponding DFF
            
                end
            end
        end

        % Button pushed function: pushbutton_calcNeuropil
        function pushbutton_calcNeuropil_Callback(app, event)
            % --- PUSHBUTTON: CALCULATE NEUROPIL SIGNALS... (Executes on button press in pushbutton_calcNeuropil.)
            % FUTURE EDITS: move toward high-level functions for area, centroid, etc...
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Reload all saved ROIs
            handles.edit_nROIsToLoad.String = num2str(numel(handles.save_names)); %Last cell ID
            pushbutton_loadSavedROIs_Callback(handles.pushbutton_loadSavedROIs, eventdata, handles);
            handles = guidata(hObject);
            
            %For each ROI, get centroid and radius; generate mask for all somata
            disp('Getting neuropil data...');
            idx = find(~cellfun(@isempty,handles.save_names)); %Cell indices; skip unused save names
            centroid = struct('X',cell(numel(idx),1),'Y',cell(numel(idx),1)); %Initialize
            r_ROI = NaN(numel(idx),1);
            cellMask = false(size(handles.img{1}));
            for i = idx
                S = load(fullfile(handles.save_dir,handles.save_names{i}),'bw'); %s.bw is logical mask for ROI
                [Y,X] = find(S.bw);
                centroid(i).X = mean(X);  %Centroid of ROI
                centroid(i).Y = mean(Y);
                r_ROI(i) = sqrt(sum(S.bw(:))/pi); %Circular ROI with r = sqrt(A/pi) used for estimating neuropil mask
                cellMask = cellMask | S.bw; %Incorporate ROI into logical mask for all cell bodies
            end
            clearvars X Y
            
            for i = find(~cellfun(@isempty,handles.save_names) & ~handles.excludeROI) %Skip excluded ROIs and unused save names
                disp(['Calculating neuropil signal for cell ' num2str(i) '...']);
                %Construct neuropil mask as annulus with user-defined inside and outside
                subtractmask = false(size(cellMask));
                circle = cell(2,1); %Cell array for the inner and outer circle
                R(1) = str2double(handles.edit_calcNeuropil_Ri.String); %Number of cell radii for inner diameter
                R(2) = str2double(handles.edit_calcNeuropil_Ro.String); %Number of cell radii for outer diameter
                for j = 1:2
                    %Get grids within R(j) cell radii of centroid
                    [X,Y] = meshgrid(1:length(subtractmask));
                    circle{j} = sqrt((X - centroid(i).X).^2 + (Y - centroid(i).Y).^2)...
                        <= R(j) * r_ROI(i);   %Inner or outer radius of annulus in units of cell radius
                end
                subtractmask = circle{2} & ~circle{1} & ~cellMask; %Exclude inner circle and somata mask
                subtractmaskRadii = R; %Record radii for later reference
            
                %Get neuropil fluorescence and save
                neuropilf = nan(size(handles.stack,3),1); %Vector for neuropil signal
                idx = reshape(subtractmask,[],1); %Get linear logical indices for subtractmask
                for j = 1:size(handles.stack,3)
                    img_vect = reshape(handles.stack(:,:,j),[],1); %Convert the j-th frame to vector
                    neuropilf(j) = mean(img_vect(idx)); %Get mean pixel intensity
                end
                save(fullfile(handles.save_dir,handles.save_names{i}),'neuropilf','subtractmask','subtractmaskRadii','-append');
            
                handles.neuropilf{i} = neuropilf; %Store neuropil data
                handles.npPoly(i) = getNpPoly(app, subtractmask); %Generate polygon representation (graphics object)
            end
            disp('Done!');
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, 1:3,handles);
        end

        % Button pushed function: pushbutton_delROI
        function pushbutton_delROI_Callback(app, event)
            % --- PUSHBUTTON: DELETE (Executes on button press in pushbutton_delROI or keypress 'delete')
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            idx = str2double(handles.edit_cellID.String);
            A = questdlg(strjoin({'Permanently delete',handles.save_names{idx},'?'}),...
                'Delete ROI','Ok','Cancel','Ok');
            if strcmp(A,'Ok')
                %Recycle file
                recycle('on');
                delete(fullfile(handles.save_dir,handles.save_names{idx}));
                %Clear ROI data
                handles.roi{idx} = [];
                handles.cellf{idx} = [];
                handles.cellMasksAll(:,:,idx) = ...
                    false(size(handles.stack,1),size(handles.stack,2)); %3D array of logical masks: nY x nX x nROIs
                handles.curr_ROI = [];
                handles.curr_cellf = [];
                handles.excludeROI(idx) = false;
                handles.save_names{idx} = [];
            else
                return
            end
            
            handles = delete_refFig(app, handles); %Delete reference figure (longitudinal imaging only)
            
            [handles,~] = nxtCellID(app, handles); %Increment cell ID for save
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, (1:4),handles);
        end

        % Button pushed function: pushbutton_loadSavedROIs
        function pushbutton_loadSavedROIs_Callback(app, event)
            % --- PUSHBUTTON: LOAD (Executes on button press in pushbutton_loadSavedROIs.)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %If IMPORT box checked, import all ROI dirs in current data dir (for longitudinal imaging)
            if handles.checkbox_ImportROIs.Value
                parts = strsplit(handles.pathname,filesep); %Split pathname
                data_dir = strjoin(parts(1:end-2),filesep); %Retrieve parent dir
                handles = transferROIs(app, data_dir,handles);
                handles = initUserData(app, handles);
                handles.edit_nROIsToLoad.String = num2str(numel(handles.save_names));
            else
                handles = initUserData(app, handles); %Initialize struct userdata
            end
            
            for i = 1:numel(handles.save_names)
            
                %Stop loading ROIs at user-defined cell ID
                if i > str2double(handles.edit_nROIsToLoad.String) %Last three-digit cell ID to load
                    break
                elseif isempty(handles.save_names{i})
                    continue
                end
            
                %Load saved ROI data
                warning('off','MATLAB:load:variableNotFound');
                try
                    S = load(fullfile(handles.save_dir,handles.save_names{i}),...
                        'bw','cellf','subtractmask','neuropilf'); %s.bw is logical mask
                    S.bw = imresize(S.bw,size(handles.stack,[1,2])); %Resize if necessary
                    if isfield(S,'subtractmask')
                        S.subtractmask = imresize(S.subtractmask,size(handles.stack,[1,2])); %Resize if necessary
                        if handles.checkbox_loadNeuropilData.Value
                            handles.neuropilf{i} = S.neuropilf;         %Get saved neuropil data
                            handles.npPoly(i) = getNpPoly(app, S.subtractmask); %Generate polygon representation (graphics object)
                        end
                    end
            
                    handles.cellMasksAll(:,:,i) = S.bw; %3D array of logical masks: nY x nX x nROIs
                    bounds = bwboundaries(S.bw); %Generate polygon representation of each cell mask
                    handles.roi{i} = [bounds{1}(:,2) bounds{1}(:,1)]; %ROI coordinates in xy
                catch err
                    disp(err); beep;
                    warning(['Problem with ' handles.save_names{i}...
                        '. May require user to manually delete file and reload ROIs.']);
                end
            
                %Generate exclude mask from saved data
                handles.cellf{i} = S.cellf;
                if isempty(S.cellf)
                    handles.excludeROI(i) = true; %Excluded ROIs have full cellf timeseries set to NaN
                else
                    handles.excludeROI(i) = false;
                end
            
            end
            
            [handles,~] = nxtCellID(app, handles); %Increment cell ID for save
            
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, (1:4),handles); %Refresh left & right axes
            saveRefImg(app, handles) %Save a reference image of axes2 including ROIs
        end

        % Button pushed function: pushbutton_loadStack
        function pushbutton_loadimagestack_Callback(app, event)
            % ---PUSHBUTTON: LOAD STACK (executes on button press).
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Clear data from any previous loaded stack
            fields = {'stack','mean_proj','max_proj','var_proj'};
            for i = find(isfield(handles,fields))
                handles = rmfield(handles,fields{i});
            end
            
            %Prompt user to select a .TIF stack containing calcium imaging data
            [handles.filename, handles.pathname] = ...
                uigetfile('*.tif', 'Select a time-lapse image stack (*.TIF).'); %Get filename and path
            
            %Load stack and generate projections
            handles = loadImageStack(app, handles); %pathname and filename for stack as .TIF
            
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, (1:4),handles);
        end

        % Button pushed function: pushbutton_recalcCorr
        function pushbutton_recalcCorr_Callback(app, event)
            %--- PUSHBUTTON: RECALC pixel correlation (executes on corresponding button press)
            %    note: also performs initial calculation after pushbutton SELECT PIXEL is pressed.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Store active select-function
            handles.recalcCorr_active = true; %Pixel-correlation --> ROI
            handles.selectCircle_active = false; %Circle ROI
            
            %Determine square to correlate
            [nX,nY,~] = size(handles.stack);
            X = handles.ginput{1}; %ginput from SELECT PIXEL pushbutton callback
            Y = handles.ginput{2};
            
            if (X<0) || (X>nX) || (Y<0) || (Y>nY), return, end
            
            width = str2double(get(handles.edit_boxWidthXCorr,'String'));
            if isempty(width)
                %Get correlation with all pixels in frame
                x = [1,nX];
                y = [1,nY];
            else
                %Get X and Y boundaries of pixel region to correlate
                x = round(X + [-width/2, width/2]); %ginput from Select Pixel pushbutton callback
                y = round(Y + [-width/2, width/2]);
                %Set values outside frame to nearest pixel
                x = max([x;1,1]); x = min([x;nX,nX]);
                y = max([y;1,1]); y = min([y;nY,nY]);
            end
            
            %Calculate matrix of correlation coefficients between seed and each pixel
            cc_mat = zeros(nY,nX);
            seedPixel = squeeze(handles.stack(round(Y),round(X),:)); %Seed pixel
            for i = x(1):x(2)
                for j = y(1):y(2)
                    cc_mat(j,i) = corr2(...
                        seedPixel, squeeze(handles.stack(j,i,:))); %Pixel (j,i)
                end
            end
            
            %Threshold by percentile specified in edit box
            temp = cc_mat(cc_mat>0);
            thresh_perc = min([max([0,str2double(handles.edit_XCorrLowerBound.String)*100]),100]);
            low_bound = prctile(temp,thresh_perc); %Get lower bound from edit box
            
            %Plot histogram of R-values
            cla(handles.axes4);
            hold(handles.axes4,'on');
            edges = [-1:0.02:1];
            counts = histc(cc_mat(:),edges);
            bar(handles.axes4,edges,counts,'histc');
            plot(handles.axes4,[low_bound low_bound],[0 1.1*max(counts)],'r','LineWidth',1);
            xlim(handles.axes4,[0,1]);
            temp = max(counts(edges>low_bound));
            
            if isempty(temp)
                return
            else
                %Display patch and corresponding DFF
                ylim(handles.axes4,[0,1.1*temp]); %ylim set to 1.1 * maximum count above threshold
                BW = (cc_mat>low_bound); %Get logical mask of pixels exceeding threshold
                BW = ~bwareaopen(~BW,10,8); %Remove small holes from pixel mask
                handles.cellMask = bwareafilt(BW,1,4); %Keep only the largest region; 'conn'=4
            
                [handles.curr_ROI, handles.curr_cellf] = roiData(app, handles); %Store cellF and patch vertices in memory
            
                guidata(hObject, handles); %Save user data
                refresh_Axis(app, (1:3),handles); %Refresh left & right axes
            end
        end

        % Button pushed function: pushbutton_saveCellF
        function pushbutton_savetraces_Callback(app, event)
            %--- PUSHBUTTON: SAVE (executes on corresponding button press) ------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Check for necessary variables
            if isempty(handles.cellMask) %Eg if error in constructing mask or if saved cell selected
                return
            end
            
            %Check if save would overwrite; verify with user before continuing
            save_name = getSaveName(app, handles,str2double(handles.edit_cellID.String));
            handles.check_overwrite = true; %Set back to default: check before overwriting
            
            %Save data for current ROI
            S = struct('cellf',handles.curr_cellf,'bw',handles.cellMask);
            save(fullfile(handles.save_dir,save_name),'-struct','S');
            
            %Store data in structure
            idx = str2double(handles.edit_cellID.String); %Edit box can be changed by function getSaveName(handles,idx)
            handles.roi{idx} = handles.curr_ROI; %Store vertices of current ROI patch
            handles.cellMasksAll(:,:,idx) = handles.cellMask; %3D array of logical masks: nY x nX x nROIs
            handles.cellf{idx} = handles.curr_cellf; %Store cellF from current ROI patch
            if ~isempty(handles.curr_cellf)
                handles.excludeROI(idx) = false; %Restore default, eg when transferring longitudinal ROIs
            end
            
            handles.save_names{idx} = save_name; %Store save_name
            [handles,~] = nxtCellID(app, handles); %Increment cell ID
            
            %Refresh axes
            handles.curr_ROI = []; handles.curr_cellf = []; handles.cellMask = []; %Clear current ROI data
            refresh_Axis(app, (1:4),handles);
            handles = delete_refFig(app, handles); %Delete reference figure (longitudinal imaging only)
            
            %Store user data
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_selectCircle
        function pushbutton_selectCircle_Callback(app, event)
            % --- PUSHBUTTON: SELECT CIRCLE (executes on button press.)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Store active select-function
            handles.selectCircle_active = true; %Circle ROI
            handles.recalcCorr_active = false; %Pixel-correlation --> ROI
            
            %Refresh axes
            %handles.curr_ROI = [];
            refresh_Axis(app, [1:4],handles);
            
            %Get selected pixel and test against frame boundaries
            [nY,nX,~] = size(handles.stack);
            [X,Y] = ginput(1);
            if (X<0) || (X>nX) || (Y<0) || (Y>nY), return, end
            
            %Determine the approx. circular logical mask
            r = str2double(get(handles.edit_circRadius,'String'));
            [rr,cc] = meshgrid(1:nX);
            handles.cellMask = sqrt((rr-X).^2+(cc-Y).^2)<=r; %Get grids within circle radius
            %Store cellF and patch vertices in memory
            [handles.curr_ROI, handles.curr_cellf] = roiData(app, handles);
            
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, (1:3),handles);
        end

        % Button pushed function: pushbutton_selectCorrPixel
        function pushbutton_selectCorrPixel_Callback(app, event)
            %--- PUSHBUTTON: SELECT PIXEL for correlation (executes on corresponding button press)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Refresh axes
            refresh_Axis(app, (1:4),handles);
            
            %Get seed pixel specified by user
            [handles.ginput{1},handles.ginput{2}] = ginput(1); %[X,Y] = ginput(nPoints);
            
            %Calculate pixel-wise correlation with
            pushbutton_recalcCorr_Callback(handles.pushbutton_recalcCorr,eventdata,handles); %Callback function for 'Recalc' pushbutton
            handles = guidata(hObject); %Expicitly set handles assigned by callback function for different uicontrol
            
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_selectPolygon
        function pushbutton_selectPolygon_Callback(app, event)
            % --- PUSHBUTTON: SELECT POLYGON (executes on corresponding button press.)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Refresh axes
            %handles.curr_ROI = [];
            refresh_Axis(app, (1:4),handles);
            
            %Get arbitrary polygonal ROI from user input
            handles.cellMask = roipoly; %Get logical mask approximation of polygon drawn by user
            
            %Display patch and corresponding DFF
            [handles.curr_ROI, handles.curr_cellf] = roiData(app, handles); %Store cellF and patch vertices in memory
            
            refresh_Axis(app, (1:3),handles); %Refresh left & right axes
            guidata(hObject, handles);
        end

        % Value changed function: slider_shiftX
        function slider_shiftX_Callback(app, event)
            % --- SLIDERS: TRANSLATION (X-Y shift for reference image, Axis 1) (executes on mouse-click) --
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.text_shiftX.String = ['dx = ' num2str(round(handles.slider_shiftX.Value))]; %Update display
            handles.img{1} = getProjection(app, 1,handles); %img = getProjection(axisID, handles)
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, 1,handles);
        end

        % Value changed function: slider_shiftY
        function slider_shiftY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.text_shiftY.String = ['dy = ' num2str(round(handles.slider_shiftY.Value))]; %Update display
            handles.img{1} = getProjection(app, 1,handles); %img = getProjection(axisID, handles)
            guidata(hObject, handles); %Save user data
            refresh_Axis(app, 1,handles);
        end

        % Value changed function: slider_whiteLevelAxis1
        function slider_whiteLevelAxis1_Callback(app, event)
            % --- SLIDERS: WHITE LEVEL (executes on mouse-click) ----------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Get projection of data, apply white level, then refresh Axis 1
            handles.text_whiteLevelAxis1.String = num2str(handles.slider_whiteLevelAxis1.Value);
            handles.img{1} = getProjection(app, 1,handles); %img = getProjection(axisID, handles)
            refresh_Axis(app, 1,handles); %Refresh graphics and make current axis
            guidata(hObject, handles);
        end

        % Value changed function: slider_whiteLevelAxis2
        function slider_whiteLevelAxis2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Get projection of data, apply white level, then refresh Axis 1
            handles.text_whiteLevelAxis2.String = num2str(handles.slider_whiteLevelAxis2.Value); %Update display
            handles.img{2} = getProjection(app, 2,handles); %img = getProjection(axisID, handles)
            refresh_Axis(app, 2,handles); %Refresh graphics and make current axis
            guidata(hObject, handles);
        end

        % Value changed function: togglebutton_excludeROI
        function togglebutton_excludeROI_Callback(app, event)
            % --- TOGGLEBUTTON: EXCLUDE (Executes on button press in togglebutton_excludeROI.)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            idx = str2double(handles.edit_cellID.String); %Get cell ID
            
            if isempty(handles.cellMask)
                S = load(fullfile(handles.save_dir,handles.save_names{idx}),'bw');
                handles.cellMask = S.bw;
            end
            
            handles.check_overwrite = false;
            if hObject.Value == hObject.Max
                handles.excludeROI(idx) = true; %For refreshing ROI mask
                handles.curr_cellf = []; %Exclude ROI data
            elseif hObject.Value == hObject.Min
                %handles.curr_ROI = handles.roi{idx}; %Make these vertices the current ROI
                handles.excludeROI(idx) = false;
                [~, handles.curr_cellf] = roiData(app, handles); %Get cellf and save to .MAT file
            end
            pushbutton_savetraces_Callback(handles.pushbutton_saveCellF, [], handles);  %Update .MAT file
            
            handles.togglebutton_excludeROI.Value = handles.togglebutton_excludeROI.Min; %Toggle button off
            
            handles = guidata(hObject); %Explicitly repopulate userdata struct after calling pushbutton functions
            guidata(hObject, handles);  %Save user data
            refresh_Axis(app, 1:3,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'cellROI';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1_SizeChangedFcn, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1_WindowButtonDownFcn, true);
            app.figure1.WindowScrollWheelFcn = createCallbackFcn(app, @figure1_WindowScrollWheelFcn, true);
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1_WindowKeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XTick = zeros(1,0);
            app.axes2.YTick = zeros(1,0);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = zeros(1,0);
            app.axes1.YTick = zeros(1,0);
            app.axes1.Tag = 'axes1';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Manual ROI Selection';
            app.uipanel9.Tag = 'uipanel9';
            app.pushbutton_selectPolygon = uibutton(app.uipanel9, 'push');
            app.pushbutton_selectPolygon.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectPolygon_Callback, true);
            app.pushbutton_selectPolygon.Tag = 'pushbutton_selectPolygon';
            app.pushbutton_selectPolygon.Tooltip = 'Press ''z'' to draw a polygon.';
            app.pushbutton_selectPolygon.Text = 'Draw Polygon';
            app.text_circRadius = uilabel(app.uipanel9);
            app.text_circRadius.Tag = 'text_circRadius';
            app.text_circRadius.Text = 'Radius:';
            app.edit_circRadius = uieditfield(app.uipanel9, 'text');
            app.edit_circRadius.ValueChangedFcn = createCallbackFcn(app, @edit_circRadius_Callback, true);
            app.edit_circRadius.Tag = 'edit_circRadius';
            app.edit_circRadius.Value = '6';
            app.pushbutton_selectCircle = uibutton(app.uipanel9, 'push');
            app.pushbutton_selectCircle.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectCircle_Callback, true);
            app.pushbutton_selectCircle.Tag = 'pushbutton_selectCircle';
            app.pushbutton_selectCircle.Tooltip = 'Press ''c'' to draw a circle.';
            app.pushbutton_selectCircle.Text = 'Draw Circle';
            app.uipanel_shiftXY = uipanel(app.figure1);
            app.uipanel_shiftXY.Title = 'Translation';
            app.uipanel_shiftXY.Tag = 'uipanel_shiftXY';
            app.slider_shiftX = uislider(app.uipanel_shiftXY);
            app.slider_shiftX.Limits = [-20 20];
            app.slider_shiftX.MajorTicks = [];
            app.slider_shiftX.ValueChangedFcn = createCallbackFcn(app, @slider_shiftX_Callback, true);
            app.slider_shiftX.Tag = 'slider_shiftX';
            app.slider_shiftY = uislider(app.uipanel_shiftXY);
            app.slider_shiftY.Limits = [-20 20];
            app.slider_shiftY.MajorTicks = [];
            app.slider_shiftY.ValueChangedFcn = createCallbackFcn(app, @slider_shiftY_Callback, true);
            app.slider_shiftY.Tag = 'slider_shiftY';
            app.text_shiftX = uilabel(app.uipanel_shiftXY);
            app.text_shiftX.Tag = 'text_shiftX';
            app.text_shiftX.Text = 'dx = 0';
            app.text_shiftY = uilabel(app.uipanel_shiftXY);
            app.text_shiftY.Tag = 'text_shiftY';
            app.text_shiftY.Text = 'dy = 0';
            app.uipanel_XCorr = uipanel(app.figure1);
            app.uipanel_XCorr.Title = 'Pixel Correlation';
            app.uipanel_XCorr.Tag = 'uipanel_XCorr';
            app.axes4 = uiaxes(app.uipanel_XCorr);
            app.axes4.XTick = zeros(1,0);
            app.axes4.YTick = zeros(1,0);
            app.axes4.Tag = 'axes4';
            app.pushbutton_selectCorrPixel = uibutton(app.uipanel_XCorr, 'push');
            app.pushbutton_selectCorrPixel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectCorrPixel_Callback, true);
            app.pushbutton_selectCorrPixel.Tag = 'pushbutton_selectCorrPixel';
            app.pushbutton_selectCorrPixel.Tooltip = 'Press ''x'' to select a seed pixel for correlation.';
            app.pushbutton_selectCorrPixel.Text = 'Select Pixel';
            app.pushbutton_loadStack = uibutton(app.figure1, 'push');
            app.pushbutton_loadStack.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadimagestack_Callback, true);
            app.pushbutton_loadStack.Tag = 'pushbutton_loadStack';
            app.pushbutton_loadStack.Text = 'Load Image Stack';
            app.text_stackSize = uilabel(app.figure1);
            app.text_stackSize.Tag = 'text_stackSize';
            app.text_stackSize.Text = 'No image stack loaded.';
            app.edit_XCorrLowerBound = uieditfield(app.figure1, 'text');
            app.edit_XCorrLowerBound.ValueChangedFcn = createCallbackFcn(app, @edit_XCorrLowerBound_Callback, true);
            app.edit_XCorrLowerBound.Tag = 'edit_XCorrLowerBound';
            app.edit_XCorrLowerBound.Value = '0.8';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Select if CDF >';
            app.edit_boxWidthXCorr = uieditfield(app.figure1, 'text');
            app.edit_boxWidthXCorr.ValueChangedFcn = createCallbackFcn(app, @edit_boxWidthXCorr_Callback, true);
            app.edit_boxWidthXCorr.Tag = 'edit_boxWidthXCorr';
            app.edit_boxWidthXCorr.Value = '25';
            app.text_boxWidthXCorr = uilabel(app.figure1);
            app.text_boxWidthXCorr.Tag = 'text_boxWidthXCorr';
            app.text_boxWidthXCorr.Text = 'Box width:';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Projection type';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton_meanAxis2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_meanAxis2.Tag = 'radiobutton_meanAxis2';
            app.radiobutton_meanAxis2.Text = 'Mean';
            app.radiobutton_meanAxis2.Value = true;
            app.radiobutton_maxAxis2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_maxAxis2.Tag = 'radiobutton_maxAxis2';
            app.radiobutton_maxAxis2.Text = 'Max';
            app.radiobutton_varAxis2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_varAxis2.Tag = 'radiobutton_varAxis2';
            app.radiobutton_varAxis2.Text = 'Variance';
            app.checkbox_showROIs = uicheckbox(app.uibuttongroup1);
            app.checkbox_showROIs.ValueChangedFcn = createCallbackFcn(app, @checkbox_showROIs_Callback, true);
            app.checkbox_showROIs.Tag = 'checkbox_showROIs';
            app.checkbox_showROIs.Text = 'Display ROIs';
            app.checkbox_showROIs.Value = true;
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'Projection type';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.radiobutton_meanAxis1 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton_meanAxis1.Tag = 'radiobutton_meanAxis1';
            app.radiobutton_meanAxis1.Text = 'Mean';
            app.radiobutton_maxAxis1 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton_maxAxis1.Tag = 'radiobutton_maxAxis1';
            app.radiobutton_maxAxis1.Text = 'Max';
            app.radiobutton_maxAxis1.Value = true;
            app.radiobutton_varAxis1 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton_varAxis1.Tag = 'radiobutton_varAxis1';
            app.radiobutton_varAxis1.Text = 'Variance';
            app.radiobutton_redAxis1 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton_redAxis1.Tag = 'radiobutton_redAxis1';
            app.radiobutton_redAxis1.Text = 'Reference';
            app.uipanel_saveCellF = uipanel(app.figure1);
            app.uipanel_saveCellF.Title = 'Save Trace';
            app.uipanel_saveCellF.Tag = 'uipanel_saveCellF';
            app.pushbutton_saveCellF = uibutton(app.uipanel_saveCellF, 'push');
            app.pushbutton_saveCellF.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_savetraces_Callback, true);
            app.pushbutton_saveCellF.Tag = 'pushbutton_saveCellF';
            app.pushbutton_saveCellF.Tooltip = 'Press ''s'' to save currently selected ROI.';
            app.pushbutton_saveCellF.Text = 'Save';
            app.text_cellID = uilabel(app.uipanel_saveCellF);
            app.text_cellID.Tag = 'text_cellID';
            app.text_cellID.Text = 'Cell #:';
            app.edit_cellID = uieditfield(app.uipanel_saveCellF, 'text');
            app.edit_cellID.ValueChangedFcn = createCallbackFcn(app, @edit_cellID_Callback, true);
            app.edit_cellID.Tag = 'edit_cellID';
            app.edit_cellID.Value = '1';
            app.edit_saveNamePrefix = uieditfield(app.uipanel_saveCellF, 'text');
            app.edit_saveNamePrefix.Tag = 'edit_saveNamePrefix';
            app.edit_saveNamePrefix.Value = 'Prefix for save...';
            app.pushbutton_delROI = uibutton(app.uipanel_saveCellF, 'push');
            app.pushbutton_delROI.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delROI_Callback, true);
            app.pushbutton_delROI.Tag = 'pushbutton_delROI';
            app.pushbutton_delROI.Tooltip = 'Press ''d'' or ''delete'' key to delete selected ROI.';
            app.pushbutton_delROI.Text = 'Delete';
            app.togglebutton_excludeROI = uibutton(app.uipanel_saveCellF, 'state');
            app.togglebutton_excludeROI.ValueChangedFcn = createCallbackFcn(app, @togglebutton_excludeROI_Callback, true);
            app.togglebutton_excludeROI.Tag = 'togglebutton_excludeROI';
            app.togglebutton_excludeROI.Tooltip = 'Press ''e'' to save and toggle include/exclude state of selected ROI.';
            app.togglebutton_excludeROI.Text = 'Exclude';
            app.checkbox_armRepeatSelect = uicheckbox(app.uipanel_saveCellF);
            app.checkbox_armRepeatSelect.Tag = 'checkbox_armRepeatSelect';
            app.checkbox_armRepeatSelect.Text = 'Repeat ROI selection after save (''Esc'' to interrupt)';
            app.uipanel_loadROIs = uipanel(app.figure1);
            app.uipanel_loadROIs.Title = 'Load Saved ROIs';
            app.uipanel_loadROIs.Tag = 'uipanel_loadROIs';
            app.checkbox_loadNeuropilData = uicheckbox(app.uipanel_loadROIs);
            app.checkbox_loadNeuropilData.Tag = 'checkbox_loadNeuropilData';
            app.checkbox_loadNeuropilData.Text = 'Load neuropil data';
            app.pushbutton_loadSavedROIs = uibutton(app.uipanel_loadROIs, 'push');
            app.pushbutton_loadSavedROIs.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadSavedROIs_Callback, true);
            app.pushbutton_loadSavedROIs.Tag = 'pushbutton_loadSavedROIs';
            app.pushbutton_loadSavedROIs.Text = 'Load';
            app.text25 = uilabel(app.uipanel_loadROIs);
            app.text25.Tag = 'text25';
            app.text25.Text = 'ROIs # 1 to';
            app.edit_nROIsToLoad = uieditfield(app.uipanel_loadROIs, 'text');
            app.edit_nROIsToLoad.ValueChangedFcn = createCallbackFcn(app, @edit_nROIsToLoad_Callback, true);
            app.edit_nROIsToLoad.Tag = 'edit_nROIsToLoad';
            app.edit_nROIsToLoad.Value = '1';
            app.checkbox_ImportROIs = uicheckbox(app.uipanel_loadROIs);
            app.checkbox_ImportROIs.Tag = 'checkbox_ImportROIs';
            app.checkbox_ImportROIs.Text = 'Import prior ROIs';
            app.uipanel_whiteLevelAxis1 = uipanel(app.figure1);
            app.uipanel_whiteLevelAxis1.Title = 'White Level';
            app.uipanel_whiteLevelAxis1.Tag = 'uipanel_whiteLevelAxis1';
            app.slider_whiteLevelAxis1 = uislider(app.uipanel_whiteLevelAxis1);
            app.slider_whiteLevelAxis1.MajorTicks = [];
            app.slider_whiteLevelAxis1.ValueChangedFcn = createCallbackFcn(app, @slider_whiteLevelAxis1_Callback, true);
            app.slider_whiteLevelAxis1.Tag = 'slider_whiteLevelAxis1';
            app.slider_whiteLevelAxis1.Value = 1;
            app.text_whiteLevelAxis1 = uilabel(app.uipanel_whiteLevelAxis1);
            app.text_whiteLevelAxis1.Tag = 'text_whiteLevelAxis1';
            app.text_whiteLevelAxis1.Text = '1';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'White Level';
            app.uipanel8.Tag = 'uipanel8';
            app.slider_whiteLevelAxis2 = uislider(app.uipanel8);
            app.slider_whiteLevelAxis2.MajorTicks = [];
            app.slider_whiteLevelAxis2.ValueChangedFcn = createCallbackFcn(app, @slider_whiteLevelAxis2_Callback, true);
            app.slider_whiteLevelAxis2.Tag = 'slider_whiteLevelAxis2';
            app.slider_whiteLevelAxis2.Value = 1;
            app.text_whiteLevelAxis2 = uilabel(app.uipanel8);
            app.text_whiteLevelAxis2.Tag = 'text_whiteLevelAxis2';
            app.text_whiteLevelAxis2.Text = '1';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Neuropil Signals';
            app.uipanel10.Tag = 'uipanel10';
            app.pushbutton_calcNeuropil = uibutton(app.uipanel10, 'push');
            app.pushbutton_calcNeuropil.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calcNeuropil_Callback, true);
            app.pushbutton_calcNeuropil.Tag = 'pushbutton_calcNeuropil';
            app.pushbutton_calcNeuropil.Text = 'Calculate...';
            app.edit_calcNeuropil_Ri = uieditfield(app.uipanel10, 'text');
            app.edit_calcNeuropil_Ri.Tag = 'edit_calcNeuropil_Ri';
            app.edit_calcNeuropil_Ri.Value = '0';
            app.edit_calcNeuropil_Ro = uieditfield(app.uipanel10, 'text');
            app.edit_calcNeuropil_Ro.Tag = 'edit_calcNeuropil_Ro';
            app.edit_calcNeuropil_Ro.Value = '2';
            app.text33 = uilabel(app.uipanel10);
            app.text33.Tag = 'text33';
            app.text33.Text = '(inner)';
            app.text35 = uilabel(app.uipanel10);
            app.text35.Tag = 'text35';
            app.text35.Text = '(outer)';
            app.text36 = uilabel(app.uipanel10);
            app.text36.Tag = 'text36';
            app.text36.Text = {'Cell radii'; 'for mask:'};
            app.pushbutton_recalcCorr = uibutton(app.figure1, 'push');
            app.pushbutton_recalcCorr.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_recalcCorr_Callback, true);
            app.pushbutton_recalcCorr.Tag = 'pushbutton_recalcCorr';
            app.pushbutton_recalcCorr.Text = 'Recalc.';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellROI_migrate_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)cellselect_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end